classdef Supine_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        opensingalgeneratorButton       matlab.ui.control.Button
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        HardwareinitialisationTab       matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LogStatusTextArea               matlab.ui.control.TextArea
        LogStatusTextAreaLabel          matlab.ui.control.Label
        SelectPreferenceFileButton      matlab.ui.control.Button
        LoadButton                      slrealtime.ui.control.LoadButton
        TargetSelector                  slrealtime.ui.control.TargetSelector
        StartStopButton                 slrealtime.ui.control.StartStopButton
        ConnectButton                   slrealtime.ui.control.ConnectButton
        InputSignalTab                  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PassiveTorqueSavedNumeric       matlab.ui.control.NumericEditField
        MVCTorqueSavedNumeric           matlab.ui.control.NumericEditField
        PassiveTorqueSavedNmGauge       matlab.ui.control.NinetyDegreeGauge
        PassiveTorqueSavedNmGaugeLabel  matlab.ui.control.Label
        MVCValueSavedNmGauge            matlab.ui.control.NinetyDegreeGauge
        MVCValueSavedNmGaugeLabel       matlab.ui.control.Label
        HowmuchtimeforholdtheangleEditField  matlab.ui.control.NumericEditField
        HowmuchtimeforholdtheangleEditFieldLabel  matlab.ui.control.Label
        HowmanypositiondoyouneedEditField  matlab.ui.control.NumericEditField
        HowmanypositiondoyouneedEditFieldLabel  matlab.ui.control.Label
        MVCValuetoEnterNmEditField      matlab.ui.control.NumericEditField
        MVCValuetoEnterNmEditFieldLabel  matlab.ui.control.Label
        MaximumPlantarflexionRadEditField  matlab.ui.control.NumericEditField
        MaximumPlantarflexionRadLabel   matlab.ui.control.Label
        MaximumDorsiflexionRadEditField  matlab.ui.control.NumericEditField
        MaximumDorsiflexionRadEditFieldLabel  matlab.ui.control.Label
        GobackatneutralpositionButton   matlab.ui.control.Button
        StopcommandButton               matlab.ui.control.Button
        StartcommandButton              matlab.ui.control.Button
        ApplyAcquisitionParametersButton  matlab.ui.control.Button
        SelectangleRadDropDown          matlab.ui.control.DropDown
        SelectangleRadDropDownLabel     matlab.ui.control.Label
        PassiveTorquetoEnterNmSpinner   matlab.ui.control.Spinner
        PassiveTorquetoEnterNmSpinnerLabel  matlab.ui.control.Label
        DataAcquisitionTab              matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SelectaSignaltoshowDropDown_4   matlab.ui.control.DropDown
        SelectaSignaltoshowDropDown_3   matlab.ui.control.DropDown
        SelectaSignaltoshowDropDown_2   matlab.ui.control.DropDown
        SelectaSignaltoshowDropDown     matlab.ui.control.DropDown
        CommentEditField                matlab.ui.control.EditField
        CommentEditFieldLabel           matlab.ui.control.Label
        TrialNumberTextArea             matlab.ui.control.EditField
        TrialNumberEditFieldLabel       matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        TrialLengthsEditField           matlab.ui.control.EditField
        TrialLengthsEditFieldLabel      matlab.ui.control.Label
        SubjectNameEditField            matlab.ui.control.EditField
        SubjectNameEditFieldLabel       matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
    end

% Develop by Aiman Feghoul : version 0.1 Date : 29 july 2024
% General To Do 
% Change DAQ Session(Old version) to new version recommended by matlab
% Message of verification on every button and text (Verification if it is a double, char.string,etc...)
% Manage the public and private proprieties
% Comment
% Robust path


    properties (Access = public)
        DAQSession % Handle to DAQ Session
        Target_PC =slrealtime('TargetPC1');
        Preference_path;
        Models_path;
        current_model;
        Data_path;
        Datasets_path;
        file_list;
        ROM;
        MaxPF;
        MaxDF;
        model_list;% List of file on the working directory
        AcquisitionAngle;
        N_Angles;
        AcquisitionMVC;
        PassiveTorque;
        Command;
        Command_sent=false;
        Hold_Position_Time;
        model_name;
        waveform;
    end
    
    
    properties (Access = private)
        
        trialNumber = 0; % number of trial
        subjectName
        trialLength= 60;
        dataFileName
        CONVERTING_VOLTS_2_TORQUE = 20; % N*m/V
        CONVERTING_VOLTS_2_RADS = 0.1; % rad/V
        SAMPLING_FREQUENCY = 1000; % Hz
        DataAvailableListener % Listener for DAQ session 'DataAvailable' event
        TempFilename % Temporary binanry file name, acquired data is logged to this file during acquisition 
        TempFile % Handle of opended binary file, acquired data is logged to this file during acquisition 
        ProprietiesFile = 'Proprieties.mat'
        TimestampsFIFOBuffer % Timestamps FIFO buffer used for live UI plot of latest "N" seconds of acquired data
        DataFIFOBuffer % Data FIFO buffer used for live UI plot of latest "N" seconds of acquired data
        DataFIFOBuffer2
        DataFIFOBuffer3
        DataFIFOBuffer4
        Filename % Default MAT and FLB file name at app start
        Filepath = 'G:\Users\Aiman_Feghoul\Data' % = pwd + "\Data"; % Default folder for saving data
        comment
        chanNames
        plotTitle
        signalIndex
        plotTitle2
        signalIndex2
        plotTitle3
        signalIndex3
        plotTitle4
        signalIndex4

    end
    
   methods (Access = private)

        function dataAvailable_Callback(app, ~, event)
            % dataAvailable_Callback Executes on DAQ session DataAvailable event. This callback function gets executed periodically as
            % more data is acquired. For a smooth live UI plot update, it stores the latest N seconds (specified time window) of
            % acquired data and relative timestamps in FIFO buffers. A live UI plot is updated with the data in the FIFO buffer. It also writes data to a binary file.
            
            if true %app.PedalsatzerodegreesCheckBox.Value && app.PressFWDFlowCheckBox.Value % ==>x with other things available 
                % If hardware is ready
                data = [event.TimeStamps, event.Data]';
                fwrite(app.TempFile, data, 'double'); 
                currentTime = data(1,end); % current time to know if it passed the defined trial length
            end
            
            % Store continuous acquistion data in FIFO data buffers
            buffersize = app.DAQSession.Rate*10; % window time of 20 seconds
            app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, buffersize, event.TimeStamps);
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, event.Data(:,app.signalIndex)); % ==> 15: VR input
            app.DataFIFOBuffer2 = storeDataInFIFO(app, app.DataFIFOBuffer2, buffersize, event.Data(:,app.signalIndex2)); % ==> 12: Hip Laser
            app.DataFIFOBuffer3 = storeDataInFIFO(app, app.DataFIFOBuffer3, buffersize, event.Data(:,app.signalIndex3)); % ==> 12: Hip Laser
            app.DataFIFOBuffer4 = storeDataInFIFO(app, app.DataFIFOBuffer4, buffersize, event.Data(:,app.signalIndex4));
            % Update plot data
%             set(app.UIPlotLine, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer);
            plot(app.UIAxes,app.TimestampsFIFOBuffer, app.DataFIFOBuffer);
            app.UIAxes.Title.String = app.plotTitle;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end

            plot(app.UIAxes2, app.TimestampsFIFOBuffer, app.DataFIFOBuffer2);
            app.UIAxes2.Title.String = app.plotTitle2;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes2, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end

            plot(app.UIAxes3, app.TimestampsFIFOBuffer, app.DataFIFOBuffer3);
            app.UIAxes3.Title.String = app.plotTitle3;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes3, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end
            plot(app.UIAxes4, app.TimestampsFIFOBuffer, app.DataFIFOBuffer4);
            app.UIAxes4.Title.String = app.plotTitle4;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes4, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end

            % if time exceeds the defined trial length, it stops
            if currentTime >= app.trialLength
                StopButtonPushed(app,event);
            end
               
        end

        function data = storeDataInFIFO(~, data, buffersize, datablock)
            % storeDataInFIFO Store continuous acquisition data in a FIFO data buffer. Storing data in a finite-size FIFO buffer is used
            % to plot the latest "N" seconds of acquired data for a smooth live UI plot update and without continuously increasing memory use. 
            % The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the buffer exceeds the
            % specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of the data in the buffer to BUFFERSIZE. 
            % input DATA is the existing data buffer (column vector Nx1). 
            % BUFFERSIZE is the desired buffer size (maximum number of rows in DATA buffer) and can be changed. 
            % DATABLOCK is a new data block to be added to the buffer (column vector Kx1).
            % output DATA is the updated data buffer (column vector Mx1).

            % If the data size is greater than the buffer size, keep only the latest "buffer size' worth of data. This can occur if the
            % buffer size is changed to a lower value during acquisiton.

            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end

            if size(datablock,1) < buffersize
                % Data block size (numer of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size. Discard older data and append new data block, and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data, -shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new data block results in a size greater tgab tge buffer size. 
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data, -shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new data block results in a size smaller or equal to the buffer size .
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
        end

        function binFile2MAT2FLB(app, filenameIn, filenameOut)
            % BINFILE2MAT2FLB Loads 2-D array of doubles from binary file and saves data to MAT file and store data from all trials in a FLB file.
            % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading all data to memory.
            % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
            % Input binary file is a matrix of doubles with numRows x numColumns.
            % MAT file (filenameOut) is a MAT file with the following variables:float
            %   timestamps = a column vector, the first column in the data from binary file
            %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
            
            numColumns = length(app.chanNames)+1; % 1 column for time and 25 other columns for 25 channels of data

            % If filenameIn does not exist, error out 
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end

            % If out MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end

            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,1:numColumns-1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double'); 
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), 1:numColumns-1) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);

            matObj.data(:,1) =  matObj.data(:,1) .* app.CONVERTING_VOLTS_2_RADS; % left position
            matObj.data(:,2) =  matObj.data(:,2) .* app.CONVERTING_VOLTS_2_TORQUE; % left torque

            flb_data = matObj.data; 
            sampleNumber = app.SAMPLING_FREQUENCY * app.trialLength;

            % append NaN to data if not data not full tril length. i.e. STOP was pressed to end acquistion prematurely. This is done
            % so that if recording multiple realizations of the same trial, no error is generated when saving to flb
            if length(flb_data(:,1)) ~= sampleNumber % compare data length to theoretical length
                lengthDifference = sampleNumber - length(flb_data(:,1)); % determine difference in length
                fill = ones(lengthDifference,numColumns-1)*NaN; % create filler matrix to complete length
                flb_data = [flb_data; fill];
            end

%             flbName = ['G:\Users\AmirGhiasi\Data\X.flb']; % save flb
            flbName = [app.Filepath,'\',app.Filename,'.flb']; % save flb

            % backup old flb file
            if exist(flbName,'file') == 2
%                 flbNameBack = ['G:\Users\AmirGhiasi\Data\X_backup.flb'];
                flbNameBack = [app.Filepath,'\',app.Filename,'_backup.flb'];
                [success, message] = copyfile(flbName,flbNameBack);
                if success == 0
                    errordlg(message,'FLB Error','modal');
                end
            end
            % mat2flb
            D.Data = flb_data;
            D.comment = app.comment;
            D.chanName = ChannelNames(app);
            D.domainIncr = single(1/app.SAMPLING_FREQUENCY);
            D.domainName = 'time (s)';
            D.domainStart = single(1/app.SAMPLING_FREQUENCY);
            
            % save flb file
            try
                mat2flb(flbName, 'append', D);
            catch
                errordlg(['Problem savinf FLB file. Make backup copy of ' flbNameBack ' and change subject Name'], 'FLB Error', 'modal');
            end
            
        end
        
        function m = ChannelNames(app)
%             m = {'Left Foot Angle'; 'Left Torque'; 'Right Foot Angle'; 'Right Torque'; ''; 'LTA EMG'; 'LMG EMG'; 'LSol EMG'; ...
%                 'RLG EMG'; 'RTA EMG'; 'RMG EMG'; 'Hip Laser (Tibia)'; 'Righ Shank Laser (Femur)'; 'Left Shank Laser (Sacrum)'; ...
%                 'VR Input (Heel)'; 'Left Force 1'; 'Left Force 2'; 'Left Force 3'; 'Left Force 4'; 'Right Force 1'; 'Right Force 2'; ...
%                 'Right Force 3'; 'Right Force 4'; 'LLG EMG'; 'RSol EMG'};

            m = {'Position','Torque','TA EMG', 'MG EMG', 'Sol EMG','LG EMG'};
        end

        function closeApp_Callback(app, ~, event, isAcquiring)
            % closeApp_Callback Executes after "Close Confirm" dialog window. "Close Confirm" dialog window is launched from UIFigureCloseRequest.
            % before closing app is acquisition is currently on (isAcquiring=true) clean-up DAQ session and close file if logging.

            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession)
                        delete(app.DataAvailableListener)
                        delete(app.DAQSession)
                        if app.LogReuested
                            fclose(app.TempFile);
                        end
                    else
                        % acuisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           run('G:\Users\Aiman_Feghoul\Dev\reklab_public\reklabPaths.m'); % run NLID toolbox
            clc;

            delete(app.DAQSession);
            app.DAQSession = [];
     

            s = daq.createSession('dt');
            s.Rate = app.SAMPLING_FREQUENCY;
            s.IsContinuous = true;

            ch0 = addAnalogInputChannel(s,"DT9857E-SYNC","0","Voltage");
            ch1 = addAnalogInputChannel(s,"DT9857E-SYNC","1","Voltage");
            ch5 = addAnalogInputChannel(s,"DT9857E-SYNC","5","Voltage");
            ch6 = addAnalogInputChannel(s,"DT9857E-SYNC","6","Voltage");
            ch7 = addAnalogInputChannel(s,"DT9857E-SYNC","7","Voltage"); 
            ch23 = addAnalogInputChannel(s,"DT9857E-SYNC","23","Voltage");


            ch0.Range = [-10 10];
            ch1.Range = [-10 10];
            ch5.Range = [-10 10];
            ch6.Range = [-10 10];
            ch7.Range = [-10 10];
            ch23.Range = [-10 10];
          

            app.DAQSession = s;

            app.LogStatusTextArea.Value = sprintf(['1. Define the subject name and trial length.','\n','2. Connect to the target machine.','\n',...
                '3. Put pedals at zero degrees angle.','\n','4. Load the Simulink model.','\n',...
                '5. Press FWD Flow.','\n','6. Run the model.','\n','7. Ready to Record data.']);

            app.chanNames = ChannelNames(app);

            
        end

        % Value changed function: PassiveTorquetoEnterNmSpinner
        function PassiveTorquetoEnterNmSpinnerValueChanged(app, event)
            value = app.PassiveTorquetoEnterNmSpinner.Value;

            Test_Cmp =strcmp(app.SelectangleRadDropDown.Value,app.SelectangleRadDropDown.Items);
            app.PassiveTorque(find(Test_Cmp))=value;
           
        end

        % Button pushed function: SelectPreferenceFileButton
        function SelectPreferenceFileButtonPushed(app, event)
            % TO DO : 
            answer = questdlg('What would you like to do ?', ...
	        'Select a Preference File', ...
	        'create','load','search');
    % Handle response
            switch answer
                case 'create'
                    %disp([answer ' testcreate.'])
                    app.Preference_path=uigetdir('G:\Users','Select a folder to save the preference file');
                    app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
                    app.UIFigure.Visible = 'on';
                    a=dir(app.Preference_path);   % Obtains the contents of the selected path
                    for i=1:length(a)
                        if(a(i).isdir== 1 && strcmp(a(i).name,'Data'))
                           
        
                        app.Data_path = fullfile(app.Preference_path,a(i).name);
                        tempData_path=app.Data_path;
                        elseif(a(i).isdir== 1 && strcmp(a(i).name,'DataSets'))

                             app.Datasets_path = fullfile(app.Preference_path,a(i).name);
                             tempDatasets_path=app.Datasets_path;
                        elseif(a(i).isdir== 1 && strcmp(a(i).name,'Models')) 
                            
                            app.Models_path = fullfile(app.Preference_path,a(i).name);
                            temp = dir(fullfile(app.Models_path, '**', '*.slx'));
                            app.model_list={temp(:).name}';
                            if(length(temp) ~= 1)
                                app.current_model = fullfile(app.Models_path,uigetfile(app.Models_path));
                            else 
                                app.current_model = fullfile(app.Models_path,app.model_list);
                            end 
                            tempModels_path= app.Models_path;
                            TempModel2use =app.current_model;
                            
                        end
                        
                    end
                    try
                        file_preference_name=inputdlg('Enter the name of the file : ');
                        save(fullfile(app.Preference_path,cell2mat(strcat(file_preference_name,'_',app.ProprietiesFile))),'tempData_path','tempDatasets_path','tempModels_path','TempModel2use');
                        addpath(app.Models_path);
                    catch
                        uialert(app.UIFigure,'The proprieties did not save well','Error on saving file');
                    end
                     
                case 'load'
                    disp([answer ' testload.'])
                    app.Preference_path=uigetdir('G:\Users','Select a folder to save the preference file');
                    app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
                    app.UIFigure.Visible = 'on';
                    temp_preferenceFile = load(fullfile(app.Preference_path,uigetfile(app.Preference_path)));
                    dir(app.Preference_path); 

                    
                    app.Data_path=temp_preferenceFile.tempData_path;
                    app.Datasets_path=temp_preferenceFile.tempDatasets_path;
                    app.Models_path=temp_preferenceFile.tempModels_path;
                    app.current_model=temp_preferenceFile.TempModel2use;
                    addpath(app.Models_path);
                case 'search'
                    uialert(app.UIFigure,'Not available','Invalid functionnality')
                case ''
  
                    uialert(app.UIFigure,'Any preference file selected or created','Make a choice')
        
            end


            if ~isempty(app.current_model)
            app.InitializeTargetButtonPushed();
%                     sampleTime = 0.001;
%                     endTime = 5;
%                     numberOfSamples = endTime * 1/sampleTime + 1;
%                     timeVector = (0:numberOfSamples) * sampleTime;
%                     app.waveform = timeseries(timeVector*0,timeVector);
%                     Model_temp = split(app.current_model,'\');
%                     app.model_name= Model_temp{5,1};
%                     app.model_name = app.model_name(1:end-4);
%                     c= app.waveform;
%                    try
%                         open_system(app.model_name);
%                         pause(5);
%                         set_param(app.model_name,'ExternalInput','c');
%                         set_param(app.model_name,'LoadExternalInput','on');
%                         set_param(app.model_name,'StopTime','Inf');
%                         evalc('slbuild(app.model_name)');
                 %   catch
                  %      uialert(app.UIFigure,"No model chosen or No valid Model",'Make a choice')
                  %  end
            end
        end

        % Callback function
        function InitializeTargetButtonPushed(app, event)
        % To do : Add the parameter of the model on the app proprieties
%         
%         newDir = 'G:\Users\Aiman_Feghoul\Models';
%         cd(newDir);
% 
%         sampleTime = 0.001;
%         endTime = 5;
%         numberOfSamples = endTime * 1/sampleTime + 1;
%         timeVector = (0:numberOfSamples) * sampleTime;
%         c= timeseries(timeVector*0,timeVector);
            model = 'Supine_SpeedGoat_v2_ES';
            open_system(model);
%         waveform = c;
%         set_param(model,'ExternalInput','waveform');
%         set_param(model,'LoadExternalInput','on');
%         set_param(model,'StopTime','Inf');
            evalc('slbuild(model)');

        end

        % Callback function
        function LoadinputsignalfileButtonPushed(app, event)
                        

             [baseFileName, folder] = uigetfile('*.mat');
             fullFileName = fullfile(folder, baseFileName);

             setappdata(0,'Input_File_address',fullFileName);
             disp("Selected stimulus file is : ")
             disp(fullFileName)

        end

        % Button pushed function: opensingalgeneratorButton
        function opensingalgeneratorButtonPushed(app, event)
            % To DO : Function for opening the signal generator and add a
            % signal to your application
            % Modify the name of the fonction 
            %Determine the output for signal generator
            % migration tool from GUIDE to app designer
            % I Add a output for the Ali_exp_gui but need to do with with
            % app designer(waiting the gui migration tool)
            addpath('G:\Users\Aiman_Feghoul\Dev\Supine_Apparatus\signal_generator');

            Ali_exp_gui();
        end

        % Button pushed function: ApplyAcquisitionParametersButton
        function ApplyAcquisitionParametersButtonPushed(app, event)
        % To do : Set MVC to send it to Twitch computer        
            app.SelectangleRadDropDown.Value;

            head_initial_value = 0;
            head_final_value = str2double(app.SelectangleRadDropDown.Value);
            head_start_time = 0.5;
            head_finishtime = app.HowmuchtimeforholdtheangleEditField.Value;
            head_slope = 0.05;
            tail_initial_value = str2double(app.SelectangleRadDropDown.Value);
            tail_final_value = 0;
            tail_start_time = 0;
            tail_finishtime = 10;
            tail_slope = 0.05;
            if head_initial_value>head_final_value
                if head_slope>0
                  head_slope=head_slope.*-1;
                end
            end

            if tail_initial_value>tail_final_value
                if tail_slope>0
                  tail_slope=tail_slope.*-1;
                end
            end
            
            a = gen_ramp_and_hold(head_initial_value,head_final_value,head_slope,head_start_time,head_finishtime);
            b = gen_ramp_and_hold(tail_initial_value,tail_final_value,tail_slope,tail_start_time,tail_finishtime);
            Temp_command(1,:)=[a(1,:) (a(1,end)+b(1,2:end))];
            Temp_command(2,:)=[a(2,:) b(2,2:end)];
            app.Command= timeseries(Temp_command(2,:) ,Temp_command(1,:));

            % Set the passive torque and MVC on the built simulink real-time model
            % The reason we have (1,1) for both is that in the original design, we maintained an array of Passive and MVC TQs at all anke positions
            % TODO: This must be changes to a scalar from array.
            posIndx = strcmp(app.SelectangleRadDropDown.Items,app.SelectangleRadDropDown.Value);
            passive_tq_value = app.PassiveTorque(posIndx);
            mvc_tq_value = app.AcquisitionMVC(posIndx);
            setparam(app.Target_PC,'Supine_SpeedGoat_v2_ES/Visual Feedback Controller/Passive_TQ','Value',passive_tq_value);
            setparam(app.Target_PC,'Supine_SpeedGoat_v2_ES/Visual Feedback Controller/MVC_TQ','Value',mvc_tq_value);

            app.PassiveTorqueSavedNmGauge.Value = passive_tq_value;
            app.MVCValueSavedNmGauge.Value = mvc_tq_value;

            app.PassiveTorqueSavedNumeric.Value = passive_tq_value;
            app.MVCTorqueSavedNumeric.Value = mvc_tq_value;
          
        end

        % Button pushed function: StartcommandButton
        function StartcommandButtonPushed(app, event)
         % to do : problem with the lunch for the stimulation since I add
         % the recording 
         stop(app.DAQSession);
         status = getStatus(app.Target_PC.Stimulation,'all');
          if strcmp(status.In1,'RUNNING') && app.Command_sent == false
                app.StartcommandButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                stop(app.Target_PC.Stimulation,1);
                reloadData(app.Target_PC.Stimulation,1,app.Command);
                start(app.Target_PC.Stimulation,1);
                app.Command_sent = true;
          else
              uialert(app.UIfigure,"Use the go Back to neutral position Button before start a new command");
          end


        end

        % Button pushed function: StopcommandButton
        function StopcommandButtonPushed(app, event)

             app.StartcommandButton.Enable = 'on';
             app.StopButton.Enable = 'off';
             app.GobackatneutralpositionButton.Enable ="on";
             stop(app.Target_PC.Stimulation,1);
                    
        end

        % Drop down opening function: SelectangleRadDropDown
        function SelectangleRadDropDownOpening(app, event)
   % To do : Add some logic to obtain a default value 
        if ~isempty(app.MaxDF) && ~isempty(app.MaxPF) && length(app.SelectangleRadDropDown.Items)<2
        
            if(~isempty(app.N_Angles) && app.N_Angles>1)

                app.ROM = [app.MaxDF,app.MaxPF];
                angles = linspace(app.MaxDF,app.MaxPF,app.N_Angles);
                app.AcquisitionMVC = zeros(1,app.N_Angles);
                app.AcquisitionAngle=angles;
                app.PassiveTorque= zeros(1,app.N_Angles);
                for i=1:length(angles)
                    app.SelectangleRadDropDown.Items(i)= {num2str(angles(i))};
                    
                end
            else 
                uialert(app.UIFigure,'Enter a number of angles greater than one',"Invalid limit angle")
            end


        elseif length(app.AcquisitionAngle)>=2
        
        
        else
             uialert(app.UIFigure,'Enter a value of Maximum of Dorsiflexion AND Plantarflexion',"Invalid limit angle")
        end

        end

        % Value changed function: SelectangleRadDropDown
        function SelectangleRadDropDownValueChanged(app, event)


        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)

          delete(app.DAQSession);
            app.DAQSession = [];
            
            s = daq.createSession('dt');
            s.Rate = app.SAMPLING_FREQUENCY;
            s.IsContinuous = true;


            ch0 = addAnalogInputChannel(s,"DT9857E-SYNC","0","Voltage"); % pos
            ch1 = addAnalogInputChannel(s,"DT9857E-SYNC","1","Voltage"); % Torque
            ch5 = addAnalogInputChannel(s,"DT9857E-SYNC","5","Voltage"); %Left TA
            ch6 = addAnalogInputChannel(s,"DT9857E-SYNC","6","Voltage"); %Left MG
            ch7 = addAnalogInputChannel(s,"DT9857E-SYNC","7","Voltage"); % left Soleus
            ch23 = addAnalogInputChannel(s,"DT9857E-SYNC","23","Voltage"); % left LG


            ch0.Range = [-10 10];
            ch1.Range = [-10 10];
            ch5.Range = [-10 10];
            ch6.Range = [-10 10];
            ch7.Range = [-10 10];
            ch23.Range = [-10 10];

            app.DAQSession = s;


            if isempty(app.subjectName) || isempty(app.trialLength)
                errordlg('You have not defined the subject name and trial length.','Invalid Subject Name or Trial Length','modal'); % uialert function
            else
                if ~isempty(app.DAQSession) && ~(app.DAQSession.IsRunning)
        
                    % Delete existing listener for DataAvailable event
                    delete(app.DataAvailableListener);
                    app.DataAvailableListener = [];
                    
                    % Create listener for DataAvailable event and specify callback function
                    app.DataAvailableListener = addlistener(app.DAQSession,'DataAvailable', ...
                        @(src,event) dataAvailable_Callback(app, src, event));
    
                    app.trialNumber = app.trialNumber + 1;
                    app.TrialNumberTextArea.Value = num2str(app.trialNumber);

                    if isempty(app.plotTitle) || strcmp(app.plotTitle,'desired signal')
                        app.plotTitle = 'Position';
                        app.signalIndex = find(strcmp(app.chanNames,app.plotTitle));
                    end

                    if isempty(app.plotTitle2) || strcmp(app.plotTitle2,'desired signal')
                        app.plotTitle2 = 'Torque';
                        app.signalIndex2 = find(strcmp(app.chanNames,app.plotTitle2));
                    end
                    if isempty(app.plotTitle3) || strcmp(app.plotTitle3,'desired signal')
                        app.plotTitle3 = 'TA EMG';
                        app.signalIndex3 = find(strcmp(app.chanNames,app.plotTitle3));
                    end
                    if isempty(app.plotTitle4) || strcmp(app.plotTitle4,'desired signal')
                        app.plotTitle4 = 'Sol EMG';
                        app.signalIndex4 = find(strcmp(app.chanNames,app.plotTitle4));
                    end
        
                    app.RecordButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.SelectaSignaltoshowDropDown.Enable = 'off';
                    app.SelectaSignaltoshowDropDown_2.Enable = 'off';
                    app.SelectaSignaltoshowDropDown_3.Enable = 'off';
                     app.SelectaSignaltoshowDropDown_4.Enable = 'off';

                    % Create and open temporary binary file to log data to disk
                    app.TempFilename = tempname;
                    app.TempFile = fopen(app.TempFilename, 'w');
        
                    % Reset FIFO buffer data
                    app.DataFIFOBuffer = [];
                    app.DataFIFOBuffer2 = [];
                    app.DataFIFOBuffer3 = [];
                    app.DataFIFOBuffer4 = [];
                    app.TimestampsFIFOBuffer = [];
        
                    startBackground(app.DAQSession);
                    app.LogStatusTextArea.Value = 'Recording data started';
    
                end
            end

        end

        % Value changed function: SubjectNameEditField
        function SubjectNameEditFieldValueChanged(app, event)
            value = app.SubjectNameEditField.Value;
            app.subjectName = replace(value, ' ', '_');
            app.dataFileName = [app.subjectName,'_',datestr(now,'ddmmyy')];
            
        end

        % Value changed function: TrialLengthsEditField
        function TrialLengthsEditFieldValueChanged(app, event)
            app.trialLength = str2double(app.TrialLengthsEditField.Value);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             if ~isempty(app.DAQSession)
                stop(app.DAQSession);
            end
            
            app.RecordButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.SelectaSignaltoshowDropDown.Enable = 'on';
            app.SelectaSignaltoshowDropDown_2.Enable = 'on';
            app.SelectaSignaltoshowDropDown_3.Enable = 'on';
            app.SelectaSignaltoshowDropDown_4.Enable = 'on';
            app.LogStatusTextArea.Value = 'Recording data stopped.'; 

            % close temporary binary file
            fclose(app.TempFile);
            
            app.Filename = app.dataFileName;
            % convert data from binary file to mat file
            matFilepath = fullfile(app.Filepath,app.Filename);
            app.LogStatusTextArea.Value = 'Saving data in progress';
            drawnow;
            
            binFile2MAT2FLB(app, app.TempFilename, matFilepath);
           % app.trialLength = 0;
            app.LogStatusTextArea.Value = sprintf('Saving data from trial ''%d'' to ''%s'' file has completed.', app.trialNumber,app.Filename);

        end

        % Value changed function: CommentEditField
        function CommentEditFieldValueChanged(app, event)
                    app.comment = app.CommentEditField.Value;
        end

        % Value changed function: SelectaSignaltoshowDropDown
        function SelectaSignaltoshowDropDownValueChanged(app, event)
           value = app.SelectaSignaltoshowDropDown.Value;
            app.plotTitle = value;
            app.signalIndex = find(strcmp(app.chanNames,value));
        end

        % Value changed function: SelectaSignaltoshowDropDown_2
        function SelectaSignaltoshowDropDown_2ValueChanged(app, event)
          value = app.SelectaSignaltoshowDropDown_2.Value;
            app.plotTitle2 = value;
            app.signalIndex2 = find(strcmp(app.chanNames,value));
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
              isAcquiring = ~isempty(app.DAQSession) && app.DAQSession.IsRunning;
            if isAcquiring 
                question = 'Abort acquistion and close app?';

            else
                % Acquisition is stopped
                question = 'Close app?';
            end

            uiconfirm(app.UIFigure,question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,isAcquiring));
        end

        % Callback function
        function TrialNumberTextAreaValueChanged(app, event)

        end

        % Button pushed function: GobackatneutralpositionButton
        function GobackatneutralpositionButtonPushed(app, event)

    status= getStatus(app.Target_PC.Stimulation,'all');
    if strcmp(status.In1,'RUNNING')

        stop(app.Target_PC.Stimulation,1);
    
    end
    initial_value = str2double(inputdlg('What is the current voltage on the display ?','Current position needed'))*0.1;
    final_value = 0;
    start_time = 1;
    finishtime = 10;
    slope = 0.05;

    if initial_value>final_value
        if slope>0
          slope=slope.*-1;
        end
    end
    a = gen_ramp_and_hold(initial_value,final_value,slope,start_time,finishtime);

    u = timeseries(a(2,:),a(1,:));
    
    reloadData(app.Target_PC.Stimulation,1,u);
    start(app.Target_PC.Stimulation,1);
    app.Command_sent = false;
    app.StartcommandButton.Enable = 'on';
    uialert(app.UIFigure,"Wait for the pedal to return to neutral before sending a command.");

        end

        % Value changed function: MaximumDorsiflexionRadEditField
        function MaximumDorsiflexionRadEditFieldValueChanged(app, event)
            app.MaxDF = app.MaximumDorsiflexionRadEditField.Value;
            
        end

        % Value changed function: MaximumPlantarflexionRadEditField
        function MaximumPlantarflexionRadEditFieldValueChanged(app, event)
            app.MaxPF = app.MaximumPlantarflexionRadEditField.Value;
            
        end

        % Value changed function: MVCValuetoEnterNmEditField
        function MVCValuetoEnterNmEditFieldValueChanged(app, event)
            value = app.MVCValuetoEnterNmEditField.Value;

            Test_Cmp =strcmp(app.SelectangleRadDropDown.Value,app.SelectangleRadDropDown.Items);
            app.AcquisitionMVC(find(Test_Cmp))=value;

        end

        % Value changed function: HowmanypositiondoyouneedEditField
        function HowmanypositiondoyouneedEditFieldValueChanged(app, event)
            app.N_Angles = app.HowmanypositiondoyouneedEditField.Value;
            
        end

        % Value changed function: HowmuchtimeforholdtheangleEditField
        function HowmuchtimeforholdtheangleEditFieldValueChanged(app, event)
            app.Hold_Position_Time = app.HowmuchtimeforholdtheangleEditField.Value;
            
        end

        % Value changed function: SelectaSignaltoshowDropDown_3
        function SelectaSignaltoshowDropDown_3ValueChanged(app, event)
            value = app.SelectaSignaltoshowDropDown_3.Value;
            app.plotTitle3 = value;
            app.signalIndex3 = find(strcmp(app.chanNames,value));
            
        end

        % Value changed function: SelectaSignaltoshowDropDown_4
        function SelectaSignaltoshowDropDown_4ValueChanged(app, event)
            value = app.SelectaSignaltoshowDropDown_4.Value;
            app.plotTitle4 = value;
            app.signalIndex4 = find(strcmp(app.chanNames,value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.HardwareinitialisationTab = uitab(app.TabGroup);
            app.HardwareinitialisationTab.Title = 'Hardware initialisation';
            app.GridLayout2 = uigridlayout(app.HardwareinitialisationTab);
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.GridLayout2);
            app.StartStopButton = slrealtime.ui.control.StartStopButton(app.GridLayout2);
            app.TargetSelector = slrealtime.ui.control.TargetSelector(app.GridLayout2);
            app.LoadButton = slrealtime.ui.control.LoadButton(app.GridLayout2);
            app.SelectPreferenceFileButton = uibutton(app.GridLayout2, 'push');
            app.SelectPreferenceFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPreferenceFileButtonPushed, true);
            app.SelectPreferenceFileButton.Text = 'Select Preference File';
            app.LogStatusTextAreaLabel = uilabel(app.GridLayout2);
            app.LogStatusTextAreaLabel.Text = 'Log Status';
            app.LogStatusTextArea = uitextarea(app.GridLayout2);
            app.InputSignalTab = uitab(app.TabGroup);
            app.InputSignalTab.Title = 'Input Signal';
            app.GridLayout3 = uigridlayout(app.InputSignalTab);
            app.PassiveTorquetoEnterNmSpinnerLabel = uilabel(app.GridLayout3);
            app.PassiveTorquetoEnterNmSpinnerLabel.Text = 'Passive Torque to Enter (Nm)';
            app.PassiveTorquetoEnterNmSpinner = uispinner(app.GridLayout3);
            app.PassiveTorquetoEnterNmSpinner.ValueChangedFcn = createCallbackFcn(app, @PassiveTorquetoEnterNmSpinnerValueChanged, true);
            app.SelectangleRadDropDownLabel = uilabel(app.GridLayout3);
            app.SelectangleRadDropDownLabel.Text = 'Select angle (Rad)';
            app.SelectangleRadDropDown = uidropdown(app.GridLayout3);
            app.SelectangleRadDropDown.Items = {'Enter the limit before'};
            app.SelectangleRadDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectangleRadDropDownOpening, true);
            app.SelectangleRadDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectangleRadDropDownValueChanged, true);
            app.SelectangleRadDropDown.Value = 'Enter the limit before';
            app.ApplyAcquisitionParametersButton = uibutton(app.GridLayout3, 'push');
            app.ApplyAcquisitionParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAcquisitionParametersButtonPushed, true);
            app.ApplyAcquisitionParametersButton.Text = 'Apply Acquisition Parameters';
            app.StartcommandButton = uibutton(app.GridLayout3, 'push');
            app.StartcommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartcommandButtonPushed, true);
            app.StartcommandButton.Text = 'Start command';
            app.StopcommandButton = uibutton(app.GridLayout3, 'push');
            app.StopcommandButton.ButtonPushedFcn = createCallbackFcn(app, @StopcommandButtonPushed, true);
            app.StopcommandButton.Text = 'Stop command';
            app.GobackatneutralpositionButton = uibutton(app.GridLayout3, 'push');
            app.GobackatneutralpositionButton.ButtonPushedFcn = createCallbackFcn(app, @GobackatneutralpositionButtonPushed, true);
            app.GobackatneutralpositionButton.Text = 'Go back at neutral position';
            app.MaximumDorsiflexionRadEditFieldLabel = uilabel(app.GridLayout3);
            app.MaximumDorsiflexionRadEditFieldLabel.Text = 'Maximum Dorsiflexion (Rad)';
            app.MaximumDorsiflexionRadEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaximumDorsiflexionRadEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumDorsiflexionRadEditFieldValueChanged, true);
            app.MaximumPlantarflexionRadLabel = uilabel(app.GridLayout3);
            app.MaximumPlantarflexionRadLabel.Text = 'Maximum Plantarflexion (Rad)';
            app.MaximumPlantarflexionRadEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaximumPlantarflexionRadEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPlantarflexionRadEditFieldValueChanged, true);
            app.MVCValuetoEnterNmEditFieldLabel = uilabel(app.GridLayout3);
            app.MVCValuetoEnterNmEditFieldLabel.Text = 'MVC Value to Enter (Nm)';
            app.MVCValuetoEnterNmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MVCValuetoEnterNmEditField.ValueChangedFcn = createCallbackFcn(app, @MVCValuetoEnterNmEditFieldValueChanged, true);
            app.HowmanypositiondoyouneedEditFieldLabel = uilabel(app.GridLayout3);
            app.HowmanypositiondoyouneedEditFieldLabel.Text = 'How many position do you need ?';
            app.HowmanypositiondoyouneedEditField = uieditfield(app.GridLayout3, 'numeric');
            app.HowmanypositiondoyouneedEditField.ValueChangedFcn = createCallbackFcn(app, @HowmanypositiondoyouneedEditFieldValueChanged, true);
            app.HowmuchtimeforholdtheangleEditFieldLabel = uilabel(app.GridLayout3);
            app.HowmuchtimeforholdtheangleEditFieldLabel.Text = 'How much time for hold the angle ?';
            app.HowmuchtimeforholdtheangleEditField = uieditfield(app.GridLayout3, 'numeric');
            app.HowmuchtimeforholdtheangleEditField.ValueChangedFcn = createCallbackFcn(app, @HowmuchtimeforholdtheangleEditFieldValueChanged, true);
            app.MVCValueSavedNmGaugeLabel = uilabel(app.GridLayout3);
            app.MVCValueSavedNmGaugeLabel.Text = 'MVC Value Saved (Nm)';
            app.MVCValueSavedNmGauge = uigauge(app.GridLayout3, 'ninetydegree');
            app.MVCValueSavedNmGauge.Limits = [-100 0];
            app.PassiveTorqueSavedNmGaugeLabel = uilabel(app.GridLayout3);
            app.PassiveTorqueSavedNmGaugeLabel.Text = 'Passive Torque Saved (Nm)';
            app.PassiveTorqueSavedNmGauge = uigauge(app.GridLayout3, 'ninetydegree');
            app.PassiveTorqueSavedNmGauge.Limits = [-15 5];
            app.MVCTorqueSavedNumeric = uieditfield(app.GridLayout3, 'numeric');
            app.PassiveTorqueSavedNumeric = uieditfield(app.GridLayout3, 'numeric');
            app.DataAcquisitionTab = uitab(app.TabGroup);
            app.DataAcquisitionTab.Title = 'Data Acquisition';
            app.GridLayout4 = uigridlayout(app.DataAcquisitionTab);
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Volt')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Volt')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Volt')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Volt')
            zlabel(app.UIAxes2, 'Z')
            app.RecordButton = uibutton(app.GridLayout4, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SubjectNameEditFieldLabel = uilabel(app.GridLayout4);
            app.SubjectNameEditFieldLabel.Text = 'Subject Name';
            app.SubjectNameEditField = uieditfield(app.GridLayout4, 'text');
            app.SubjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectNameEditFieldValueChanged, true);
            app.TrialLengthsEditFieldLabel = uilabel(app.GridLayout4);
            app.TrialLengthsEditFieldLabel.Text = 'Trial Length [s]';
            app.TrialLengthsEditField = uieditfield(app.GridLayout4, 'text');
            app.TrialLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialLengthsEditFieldValueChanged, true);
            app.TrialLengthsEditField.Value = '0';
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop ';
            app.TrialNumberEditFieldLabel = uilabel(app.GridLayout4);
            app.TrialNumberEditFieldLabel.Text = 'Trial Number';
            app.TrialNumberTextArea = uieditfield(app.GridLayout4, 'text');
            app.TrialNumberTextArea.Value = '0';
            app.CommentEditFieldLabel = uilabel(app.GridLayout4);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.GridLayout4, 'text');
            app.CommentEditField.ValueChangedFcn = createCallbackFcn(app, @CommentEditFieldValueChanged, true);
            app.SelectaSignaltoshowDropDown = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown.Items = {'Position', 'Torque', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDownValueChanged, true);
            app.SelectaSignaltoshowDropDown.Value = 'Position';
            app.SelectaSignaltoshowDropDown_2 = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown_2.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDown_2ValueChanged, true);
            app.SelectaSignaltoshowDropDown_2.Value = 'Position';
            app.SelectaSignaltoshowDropDown_3 = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown_3.Items = {'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDown_3ValueChanged, true);
            app.SelectaSignaltoshowDropDown_3.Value = 'TA EMG';
            app.SelectaSignaltoshowDropDown_4 = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown_4.Items = {'Sol EMG', 'TA EMG', 'MG EMG', 'LG EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDown_4ValueChanged, true);
            app.SelectaSignaltoshowDropDown_4.Value = 'Sol EMG';
            app.opensingalgeneratorButton = uibutton(app.UIFigure, 'push');
            app.opensingalgeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @opensingalgeneratorButtonPushed, true);
            app.opensingalgeneratorButton.Text = 'open singal generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Supine_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end