classdef Supine_GUI_seperate_tabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        HardwareinitialisationTab       matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LogStatusPanel                  matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        LogStatusTextArea               matlab.ui.control.TextArea
        SelectPreferenceFileButton      matlab.ui.control.Button
        LoadButton                      slrealtime.ui.control.LoadButton
        TargetSelector                  slrealtime.ui.control.TargetSelector
        StartStopButton                 slrealtime.ui.control.StartStopButton
        ConnectButton                   slrealtime.ui.control.ConnectButton
        InputSignalTab                  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PassiveTorqueSavedNumeric       matlab.ui.control.NumericEditField
        MVCTorqueSavedNumeric           matlab.ui.control.NumericEditField
        PassiveTorqueSavedNmGauge       matlab.ui.control.NinetyDegreeGauge
        PassiveTorqueSavedNmGaugeLabel  matlab.ui.control.Label
        MVCValueSavedNmGauge            matlab.ui.control.NinetyDegreeGauge
        MVCValueSavedNmGaugeLabel       matlab.ui.control.Label
        HowmuchtimeforholdtheangleEditField  matlab.ui.control.NumericEditField
        HowmuchtimeforholdtheangleEditFieldLabel  matlab.ui.control.Label
        HowmanypositiondoyouneedEditField  matlab.ui.control.NumericEditField
        HowmanypositiondoyouneedEditFieldLabel  matlab.ui.control.Label
        MVCValuetoEnterNmEditField      matlab.ui.control.NumericEditField
        MVCValuetoEnterNmEditFieldLabel  matlab.ui.control.Label
        MaximumPlantarflexionRadEditField  matlab.ui.control.NumericEditField
        MaximumPlantarflexionRadLabel   matlab.ui.control.Label
        MaximumDorsiflexionRadEditField  matlab.ui.control.NumericEditField
        MaximumDorsiflexionRadEditFieldLabel  matlab.ui.control.Label
        GobackatneutralpositionButton   matlab.ui.control.Button
        StopcommandButton               matlab.ui.control.Button
        StartcommandButton              matlab.ui.control.Button
        ApplyAcquisitionParametersButton  matlab.ui.control.Button
        SelectangleRadDropDown          matlab.ui.control.DropDown
        SelectangleRadDropDownLabel     matlab.ui.control.Label
        PassiveTorquetoEnterNmSpinner   matlab.ui.control.Spinner
        PassiveTorquetoEnterNmSpinnerLabel  matlab.ui.control.Label
        DataAcquisitionTab              matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SelectaSignaltoshowDropDown_4   matlab.ui.control.DropDown
        SelectaSignaltoshowDropDown_3   matlab.ui.control.DropDown
        SelectaSignaltoshowDropDown_2   matlab.ui.control.DropDown
        SelectaSignaltoshowDropDown     matlab.ui.control.DropDown
        CommentEditField                matlab.ui.control.EditField
        CommentEditFieldLabel           matlab.ui.control.Label
        TrialNumberTextArea             matlab.ui.control.EditField
        TrialNumberEditFieldLabel       matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        TrialLengthsEditField           matlab.ui.control.EditField
        TrialLengthsEditFieldLabel      matlab.ui.control.Label
        SubjectNameEditField            matlab.ui.control.EditField
        SubjectNameEditFieldLabel       matlab.ui.control.Label
        RecordButton                    matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MonitoringPanel                 matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        LiveFeedbackPanel               matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        LiveFeedbackDropDown_6          matlab.ui.control.DropDown
        LiveFeedbackDropDown_5          matlab.ui.control.DropDown
        LiveFeedbackDropDown_4          matlab.ui.control.DropDown
        LiveFeedbackDropDown_3          matlab.ui.control.DropDown
        LiveFeedbackDropDown_2          matlab.ui.control.DropDown
        LiveFeedbackDropDown            matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        AnklePositionLogPanel           matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        MVCPlot                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        ControlPanel                    matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        TrialRecordingPanel             matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        MVCPassiveTorquerecordingLabel  matlab.ui.control.Label
        MVCPassiveTorqueToggle          matlab.ui.control.Switch
        StopButton_2                    matlab.ui.control.Button
        RecordButton_2                  matlab.ui.control.Button
        SubjectNameEditField_2          matlab.ui.control.EditField
        SubjectNameEditField_2Label     matlab.ui.control.Label
        CommentEditField_2              matlab.ui.control.EditField
        CommentEditField_2Label         matlab.ui.control.Label
        TrialEditField                  matlab.ui.control.NumericEditField
        TrialEditFieldLabel             matlab.ui.control.Label
        TrialLengthsEditField_2         matlab.ui.control.NumericEditField
        TrialLengthsEditField_2Label    matlab.ui.control.Label
        AnklePsotionandCommandPanel     matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        LoadButton_3                    matlab.ui.control.Button
        InitializeSessionButton         matlab.ui.control.Button
        SelectAngleradDropDown          matlab.ui.control.DropDown
        SelectAngleradDropDownLabel     matlab.ui.control.Label
        GotoNeutralButton               matlab.ui.control.Button
        SaveRangeofMotiontoFileButton   matlab.ui.control.Button
        StopCommandButton               matlab.ui.control.Button
        StartCommandButton              matlab.ui.control.Button
        ApplyParametersButton           matlab.ui.control.Button
        HoldTimesEditField              matlab.ui.control.NumericEditField
        HoldTimesEditFieldLabel         matlab.ui.control.Label
        ofPositionsEditField            matlab.ui.control.NumericEditField
        ofPositionsEditFieldLabel       matlab.ui.control.Label
        MaxPlantarflexionradEditField   matlab.ui.control.NumericEditField
        MaxPlantarflexionradEditFieldLabel  matlab.ui.control.Label
        MaxDorsiflexionradEditField     matlab.ui.control.NumericEditField
        MaxDorsiflexionradEditFieldLabel  matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        MonitoringPanel_2               matlab.ui.container.Panel
        GridLayout7_2                   matlab.ui.container.GridLayout
        LiveFeedbackPanel_2             matlab.ui.container.Panel
        GridLayout12_2                  matlab.ui.container.GridLayout
        LiveFeedbackDropDown_12         matlab.ui.control.DropDown
        LiveFeedbackDropDown_11         matlab.ui.control.DropDown
        LiveFeedbackDropDown_10         matlab.ui.control.DropDown
        LiveFeedbackDropDown_9          matlab.ui.control.DropDown
        LiveFeedbackDropDown_8          matlab.ui.control.DropDown
        LiveFeedbackDropDown_7          matlab.ui.control.DropDown
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        AnklePositionLogPanel_2         matlab.ui.container.Panel
        GridLayout8_2                   matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        UIAxes7                         matlab.ui.control.UIAxes
        UITable_2                       matlab.ui.control.Table
        ControlPanel_2                  matlab.ui.container.Panel
        GridLayout6_2                   matlab.ui.container.GridLayout
        TrialRecordingPanel_2           matlab.ui.container.Panel
        GridLayout11_2                  matlab.ui.container.GridLayout
        MVCPassiveTorquerecordingLabel_2  matlab.ui.control.Label
        MVCPassiveTorqueToggle_2        matlab.ui.control.Switch
        SelectTrajectoryDropDown        matlab.ui.control.DropDown
        SelectTrajectoryDropDownLabel   matlab.ui.control.Label
        opensignalgeneratorButton       matlab.ui.control.Button
        StopButton_3                    matlab.ui.control.Button
        RecordButton_3                  matlab.ui.control.Button
        SubjectNameEditField_3          matlab.ui.control.EditField
        SubjectNameEditField_3Label     matlab.ui.control.Label
        CommentEditField_3              matlab.ui.control.EditField
        CommentEditField_3Label         matlab.ui.control.Label
        TrialEditField_2                matlab.ui.control.NumericEditField
        TrialEditField_2Label           matlab.ui.control.Label
        TrialLengthsEditField_3         matlab.ui.control.NumericEditField
        TrialLengthsEditField_3Label    matlab.ui.control.Label
        AnklePostionandCommandPanel     matlab.ui.container.Panel
        GridLayout9_2                   matlab.ui.container.GridLayout
        LoadButton_2                    matlab.ui.control.Button
        SaveRangeofMotiontoFileButton_2  matlab.ui.control.Button
        SelectAngleradDropDown_2        matlab.ui.control.DropDown
        SelectAngleradDropDown_2Label   matlab.ui.control.Label
        HoldTimesEditField_2            matlab.ui.control.NumericEditField
        HoldTimesEditField_2Label       matlab.ui.control.Label
        ofPositionsEditField_2          matlab.ui.control.NumericEditField
        ofPositionsEditField_2Label     matlab.ui.control.Label
        InitializeSessionButton_2       matlab.ui.control.Button
        GotoNeutralButton_2             matlab.ui.control.Button
        StopCommandButton_2             matlab.ui.control.Button
        StartCommandButton_2            matlab.ui.control.Button
        ApplyParametersButton_2         matlab.ui.control.Button
        MaxPlantarflexionradEditField_2  matlab.ui.control.NumericEditField
        MaxPlantarflexionradEditField_2Label  matlab.ui.control.Label
        MaxDorsiflexionradEditField_2   matlab.ui.control.NumericEditField
        MaxDorsiflexionradEditField_2Label  matlab.ui.control.Label
    end

% Develop by Aiman Feghoul : version 0.1 Date : 29 july 2024
% General To Do 
% Change DAQ Session(Old version) to new version recommended by matlab
% Message of verification on every button and text (Verification if it is a double, char.string,etc...)
% Manage the public and private proprieties
% Comment
% Robust path


    properties (Access = public)
        DAQSession % Handle to DAQ Session
        Target_PC =slrealtime('TargetPC1');
        Preference_path;
        Models_path;
        current_model;
        Data_path;
        Datasets_path;
        file_list;
        ROM;
        MaxPF;
        MaxDF;
        model_list;% List of file on the working directory
        AcquisitionAngle;
        N_Angles;
        AcquisitionMVC;
        PassiveTorque;
        Command;
        Command_sent=false;
        Hold_Position_Time;
        model_name;
        waveform;
    end
    
    
    properties (Access = private)
        
        trialNumber = 0; % number of trial
        subjectName
        trialLength= 60;
        dataFileName
        CONVERTING_VOLTS_2_TORQUE = 20; % N*m/V
        CONVERTING_VOLTS_2_RADS = 0.1; % rad/V
        SAMPLING_FREQUENCY = 1000; % Hz
        DataAvailableListener % Listener for DAQ session 'DataAvailable' event
        TempFilename % Temporary binanry file name, acquired data is logged to this file during acquisition 
        TempFile % Handle of opended binary file, acquired data is logged to this file during acquisition 
        ProprietiesFile = 'Proprieties.mat'
        TimestampsFIFOBuffer % Timestamps FIFO buffer used for live UI plot of latest "N" seconds of acquired data
        DataFIFOBuffer % Data FIFO buffer used for live UI plot of latest "N" seconds of acquired data
        DataFIFOBuffer2
        DataFIFOBuffer3
        DataFIFOBuffer4
        DataFIFOBuffer5
        DataFIFOBuffer6
        Filename % Default MAT and FLB file name at app start
        Filepath = 'G:\Users\Aiman_Feghoul\Data' % = pwd + "\Data"; % Default folder for saving data
        comment
        chanNames
        plotTitle
        signalIndex
        plotTitle2
        signalIndex2
        plotTitle3
        signalIndex3
        plotTitle4
        signalIndex4
        plotTitle5
        signalIndex5
        plotTitle6
        signalIndex6
        isMVCPassiveTorqueTrial = false; % default to false (toggle off)

    end
    
   methods (Access = private)

        function dataAvailable_Callback(app, ~, event)
            % dataAvailable_Callback Executes on DAQ session DataAvailable event. This callback function gets executed periodically as
            % more data is acquired. For a smooth live UI plot update, it stores the latest N seconds (specified time window) of
            % acquired data and relative timestamps in FIFO buffers. A live UI plot is updated with the data in the FIFO buffer. It also writes data to a binary file.
            
            if true %app.PedalsatzerodegreesCheckBox.Value && app.PressFWDFlowCheckBox.Value % ==>x with other things available 
                % If hardware is ready
                data = [event.TimeStamps, event.Data]';
                fwrite(app.TempFile, data, 'double'); 
                currentTime = data(1,end); % current time to know if it passed the defined trial length
            end
            
            % Store continuous acquistion data in FIFO data buffers
            buffersize = app.DAQSession.Rate*10; % window time of 20 seconds
            app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, buffersize, event.TimeStamps);
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, buffersize, event.Data(:,app.signalIndex)); % ==> 15: VR input
            app.DataFIFOBuffer2 = storeDataInFIFO(app, app.DataFIFOBuffer2, buffersize, event.Data(:,app.signalIndex2)); % ==> 12: Hip Laser
            app.DataFIFOBuffer3 = storeDataInFIFO(app, app.DataFIFOBuffer3, buffersize, event.Data(:,app.signalIndex3)); % ==> 12: Hip Laser
            app.DataFIFOBuffer4 = storeDataInFIFO(app, app.DataFIFOBuffer4, buffersize, event.Data(:,app.signalIndex4));
            app.DataFIFOBuffer5 = storeDataInFIFO(app, app.DataFIFOBuffer5, buffersize, event.Data(:,app.signalIndex5));
            app.DataFIFOBuffer6 = storeDataInFIFO(app, app.DataFIFOBuffer6, buffersize, event.Data(:,app.signalIndex6));
            % Update plot data
%             set(app.UIPlotLine, 'XData', app.TimestampsFIFOBuffer, 'YData', app.DataFIFOBuffer);
            plot(app.UIAxes,app.TimestampsFIFOBuffer, app.DataFIFOBuffer);
            app.UIAxes.Title.String = app.plotTitle;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end

            plot(app.UIAxes2, app.TimestampsFIFOBuffer, app.DataFIFOBuffer2);
            app.UIAxes2.Title.String = app.plotTitle2;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes2, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end

            plot(app.UIAxes3, app.TimestampsFIFOBuffer, app.DataFIFOBuffer3);
            app.UIAxes3.Title.String = app.plotTitle3;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes3, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end
            plot(app.UIAxes4, app.TimestampsFIFOBuffer, app.DataFIFOBuffer4);
            app.UIAxes4.Title.String = app.plotTitle4;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes4, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end
            plot(app.UIAxes5, app.TimestampsFIFOBuffer, app.DataFIFOBuffer5);
            app.UIAxes5.Title.String = app.plotTitle5;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes5, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end
            plot(app.UIAxes6, app.TimestampsFIFOBuffer, app.DataFIFOBuffer6);
            app.UIAxes6.Title.String = app.plotTitle6;
            if numel(app.TimestampsFIFOBuffer) > 1
                xlim(app.UIAxes6, [app.TimestampsFIFOBuffer(1), app.TimestampsFIFOBuffer(end)])
            end

            % if time exceeds the defined trial length, it stops
            if currentTime >= app.trialLength
                StopButtonPushed(app,event);
            end
               
        end

        function data = storeDataInFIFO(~, data, buffersize, datablock)
            % storeDataInFIFO Store continuous acquisition data in a FIFO data buffer. Storing data in a finite-size FIFO buffer is used
            % to plot the latest "N" seconds of acquired data for a smooth live UI plot update and without continuously increasing memory use. 
            % The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the buffer exceeds the
            % specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of the data in the buffer to BUFFERSIZE. 
            % input DATA is the existing data buffer (column vector Nx1). 
            % BUFFERSIZE is the desired buffer size (maximum number of rows in DATA buffer) and can be changed. 
            % DATABLOCK is a new data block to be added to the buffer (column vector Kx1).
            % output DATA is the updated data buffer (column vector Mx1).

            % If the data size is greater than the buffer size, keep only the latest "buffer size' worth of data. This can occur if the
            % buffer size is changed to a lower value during acquisiton.

            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end

            if size(datablock,1) < buffersize
                % Data block size (numer of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size. Discard older data and append new data block, and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data, -shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new data block results in a size greater tgab tge buffer size. 
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data, -shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new data block results in a size smaller or equal to the buffer size .
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
        end

        function binFile2MAT2FLB(app, filenameIn, filenameOut)
            % BINFILE2MAT2FLB Loads 2-D array of doubles from binary file and saves data to MAT file and store data from all trials in a FLB file.
            % Processes all data in binary file (filenameIn) and saves it to a MAT file without loading all data to memory.
            % If output MAT file (filenameOut) already exists, data is overwritten (not appended).
            % Input binary file is a matrix of doubles with numRows x numColumns.
            % MAT file (filenameOut) is a MAT file with the following variables:float
            %   timestamps = a column vector, the first column in the data from binary file
            %   data = a 2-D array of doubles, includes 2nd-last columns in the data from binary file
            
            numColumns = length(app.chanNames)+1; % 1 column for time and 25 other columns for 25 channels of data

            % If filenameIn does not exist, error out 
            if ~exist(filenameIn, 'file')
                error('Input binary file ''%s'' not found. Specify a different file name.', filenameIn);
            end

            % If out MAT file already exists, delete it
            if exist(filenameOut, 'file')
                delete(filenameOut)
            end

            % Determine number of rows in the binary file
            % Expecting the number of bytes in the file to be 8*numRows*numColumns
            fileInfo = dir(filenameIn);
            numRows = floor(fileInfo.bytes/(8*double(numColumns)));
            
            % Create matfile object to save data loaded from binary file
            matObj = matfile(filenameOut);
            matObj.Properties.Writable = true;
            
            % Initialize MAT file
            matObj.timestamps(numRows,1) = 0;
            matObj.data(numRows,1:numColumns-1) = 0;
            
            % Open input binary file
            fid = fopen(filenameIn,'r');
            
            % Specify how many rows to process(load and save) at a time
            numRowsPerChunk = 10E+6;
            
            % Keeps track of how many rows have been processed so far
            ii = 0;
            
            while(ii < numRows)
                
                % chunkSize = how many rows to process in this iteration
                % If it's the last iteration, it's possible the number of rows left to process is different from the specified numRowsPerChunk
                chunkSize = min(numRowsPerChunk, numRows-ii);
                
                data = fread(fid, [numColumns,chunkSize], 'double'); 
                
                matObj.timestamps((ii+1):(ii+chunkSize), 1) = data(1,:)';
                matObj.data((ii+1):(ii+chunkSize), 1:numColumns-1) = data(2:end,:)';
                
                ii = ii + chunkSize;
            end
            
            fclose(fid);

            matObj.data(:,1) =  matObj.data(:,1) .* app.CONVERTING_VOLTS_2_RADS; % left position
            matObj.data(:,2) =  matObj.data(:,2) .* app.CONVERTING_VOLTS_2_TORQUE; % left torque

            flb_data = matObj.data; 
            sampleNumber = app.SAMPLING_FREQUENCY * app.trialLength;

            % append NaN to data if not data not full tril length. i.e. STOP was pressed to end acquistion prematurely. This is done
            % so that if recording multiple realizations of the same trial, no error is generated when saving to flb
            if length(flb_data(:,1)) ~= sampleNumber % compare data length to theoretical length
                lengthDifference = sampleNumber - length(flb_data(:,1)); % determine difference in length
                fill = ones(lengthDifference,numColumns-1)*NaN; % create filler matrix to complete length
                flb_data = [flb_data; fill];
            end

%             flbName = ['G:\Users\AmirGhiasi\Data\X.flb']; % save flb
            flbName = [app.Filepath,'\',app.Filename,'.flb']; % save flb

            % backup old flb file
            if exist(flbName,'file') == 2
%                 flbNameBack = ['G:\Users\AmirGhiasi\Data\X_backup.flb'];
                flbNameBack = [app.Filepath,'\',app.Filename,'_backup.flb'];
                [success, message] = copyfile(flbName,flbNameBack);
                if success == 0
                    errordlg(message,'FLB Error','modal');
                end
            end
            % mat2flb
            D.Data = flb_data;
            D.comment = app.comment;
            D.chanName = ChannelNames(app);
            D.domainIncr = single(1/app.SAMPLING_FREQUENCY);
            D.domainName = 'time (s)';
            D.domainStart = single(1/app.SAMPLING_FREQUENCY);
            
            % save flb file
            try
                mat2flb(flbName, 'append', D);
            catch
                errordlg(['Problem savinf FLB file. Make backup copy of ' flbNameBack ' and change subject Name'], 'FLB Error', 'modal');
            end
            
        end
        
        function plotTorque(app, matFilepath)
            TQ = 2;
            Ts = 0.001; %sampling rate
    
            n1 = 1;
            t2 = app.trialLength - Ts;
            n2 = fix(t2/Ts);
            if app.trialNumber<=0
                uialert(app.UIFigure, 'Error indexing into recording file, 0 index', 'Error');
                return;
            end
            % read data
            data = flb2mat([matFilepath,'.flb'],'read_all');
            trial = data{1,app.trialNumber};
            torque = trial(n1:n2,TQ);
    
            t = (0:n2-n1) * Ts;
            cla(app.MVCPlot)
            plot(app.MVCPlot, t,torque.dataSet);
            ylabel(app.MVCPlot,'Torque (Nm)');
            xlabel(app.MVCPlot,'Time (s)');
            grid(app.MVCPlot,'on');
            title(app.MVCPlot,'MVC / Passive Torque Trial');

        end

        function m = ChannelNames(app)
%             m = {'Left Foot Angle'; 'Left Torque'; 'Right Foot Angle'; 'Right Torque'; ''; 'LTA EMG'; 'LMG EMG'; 'LSol EMG'; ...
%                 'RLG EMG'; 'RTA EMG'; 'RMG EMG'; 'Hip Laser (Tibia)'; 'Righ Shank Laser (Femur)'; 'Left Shank Laser (Sacrum)'; ...
%                 'VR Input (Heel)'; 'Left Force 1'; 'Left Force 2'; 'Left Force 3'; 'Left Force 4'; 'Right Force 1'; 'Right Force 2'; ...
%                 'Right Force 3'; 'Right Force 4'; 'LLG EMG'; 'RSol EMG'};

            m = {'Position','Torque','TA EMG', 'MG EMG', 'Sol EMG','LG EMG'};
        end

        function closeApp_Callback(app, ~, event, isAcquiring)
            % closeApp_Callback Executes after "Close Confirm" dialog window. "Close Confirm" dialog window is launched from UIFigureCloseRequest.
            % before closing app is acquisition is currently on (isAcquiring=true) clean-up DAQ session and close file if logging.

            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession)
                        delete(app.DataAvailableListener)
                        delete(app.DAQSession)
                        if app.LogReuested
                            fclose(app.TempFile);
                        end
                    else
                        % acuisition is stopped
                    end

                    delete(app)
                case 'Cancel'
                    % Continue
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           run('G:\Users\Aiman_Feghoul\Dev\reklab_public\reklabPaths.m'); % run NLID toolbox
            clc;

            delete(app.DAQSession);
            app.DAQSession = [];
     

            s = daq.createSession('dt');
            s.Rate = app.SAMPLING_FREQUENCY;
            s.IsContinuous = true;

            ch0 = addAnalogInputChannel(s,"DT9857E-SYNC","0","Voltage");
            ch1 = addAnalogInputChannel(s,"DT9857E-SYNC","1","Voltage");
            ch5 = addAnalogInputChannel(s,"DT9857E-SYNC","5","Voltage");
            ch6 = addAnalogInputChannel(s,"DT9857E-SYNC","6","Voltage");
            ch7 = addAnalogInputChannel(s,"DT9857E-SYNC","7","Voltage"); 
            ch23 = addAnalogInputChannel(s,"DT9857E-SYNC","23","Voltage");


            ch0.Range = [-10 10];
            ch1.Range = [-10 10];
            ch5.Range = [-10 10];
            ch6.Range = [-10 10];
            ch7.Range = [-10 10];
            ch23.Range = [-10 10];
          

            app.DAQSession = s;

            app.LogStatusTextArea.Value = sprintf(['1. Define the subject name and trial length.','\n','2. Connect to the target machine.','\n',...
                '3. Put pedals at zero degrees angle.','\n','4. Load the Simulink model.','\n',...
                '5. Press FWD Flow.','\n','6. Run the model.','\n','7. Ready to Record data.']);

            app.chanNames = ChannelNames(app);

            
        end

        % Value changed function: PassiveTorquetoEnterNmSpinner
        function PassiveTorquetoEnterNmSpinnerValueChanged(app, event)
            value = app.PassiveTorqueNmEditField.Value;

            Test_Cmp =strcmp(app.SelectAngleradDropDown.Value,app.SelectAngleradDropDown.Items);
            app.PassiveTorque(find(Test_Cmp))=value;
           
        end

        % Button pushed function: SelectPreferenceFileButton
        function SelectPreferenceFileButtonPushed(app, event)
            % TO DO : 
            answer = questdlg('What would you like to do ?', ...
	        'Select a Preference File', ...
	        'create','load','search');
    % Handle response
            switch answer
                case 'create'
                    %disp([answer ' testcreate.'])
                    app.Preference_path=uigetdir('G:\Users','Select a folder to save the preference file');
                    app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
                    app.UIFigure.Visible = 'on';
                    a=dir(app.Preference_path);   % Obtains the contents of the selected path
                    for i=1:length(a)
                        if(a(i).isdir== 1 && strcmp(a(i).name,'Data'))
                           
        
                        app.Data_path = fullfile(app.Preference_path,a(i).name);
                        tempData_path=app.Data_path;
                        elseif(a(i).isdir== 1 && strcmp(a(i).name,'DataSets'))

                             app.Datasets_path = fullfile(app.Preference_path,a(i).name);
                             tempDatasets_path=app.Datasets_path;
                        elseif(a(i).isdir== 1 && strcmp(a(i).name,'Models')) 
                            
                            app.Models_path = fullfile(app.Preference_path,a(i).name);
                            temp = dir(fullfile(app.Models_path, '**', '*.slx'));
                            app.model_list={temp(:).name}';
                            if(length(temp) ~= 1)
                                app.current_model = fullfile(app.Models_path,uigetfile(app.Models_path));
                            else 
                                app.current_model = fullfile(app.Models_path,app.model_list);
                            end 
                            tempModels_path= app.Models_path;
                            TempModel2use =app.current_model;
                            
                        end
                        
                    end
                    try
                        file_preference_name=inputdlg('Enter the name of the file : ');
                        save(fullfile(app.Preference_path,cell2mat(strcat(file_preference_name,'_',app.ProprietiesFile))),'tempData_path','tempDatasets_path','tempModels_path','TempModel2use');
                        addpath(app.Models_path);
                    catch
                        uialert(app.UIFigure,'The proprieties did not save well','Error on saving file');
                    end
                     
                case 'load'
                    disp([answer ' testload.'])
                    app.Preference_path=uigetdir('G:\Users','Select a folder to save the preference file');
                    app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
                    app.UIFigure.Visible = 'on';
                    temp_preferenceFile = load(fullfile(app.Preference_path,uigetfile(app.Preference_path)));
                    dir(app.Preference_path); 

                    
                    app.Data_path=temp_preferenceFile.tempData_path;
                    app.Datasets_path=temp_preferenceFile.tempDatasets_path;
                    app.Models_path=temp_preferenceFile.tempModels_path;
                    app.current_model=temp_preferenceFile.TempModel2use;
                    addpath(app.Models_path);
                case 'search'
                    uialert(app.UIFigure,'Not available','Invalid functionnality')
                case ''
  
                    uialert(app.UIFigure,'Any preference file selected or created','Make a choice')
        
            end


            if ~isempty(app.current_model)
            app.InitializeTargetButtonPushed();
%                     sampleTime = 0.001;
%                     endTime = 5;
%                     numberOfSamples = endTime * 1/sampleTime + 1;
%                     timeVector = (0:numberOfSamples) * sampleTime;
%                     app.waveform = timeseries(timeVector*0,timeVector);
%                     Model_temp = split(app.current_model,'\');
%                     app.model_name= Model_temp{5,1};
%                     app.model_name = app.model_name(1:end-4);
%                     c= app.waveform;
%                    try
%                         open_system(app.model_name);
%                         pause(5);
%                         set_param(app.model_name,'ExternalInput','c');
%                         set_param(app.model_name,'LoadExternalInput','on');
%                         set_param(app.model_name,'StopTime','Inf');
%                         evalc('slbuild(app.model_name)');
                 %   catch
                  %      uialert(app.UIFigure,"No model chosen or No valid Model",'Make a choice')
                  %  end
            end
        end

        % Callback function
        function InitializeTargetButtonPushed(app, event)
        % To do : Add the parameter of the model on the app proprieties
%         
%         newDir = 'G:\Users\Aiman_Feghoul\Models';
%         cd(newDir);
% 
%         sampleTime = 0.001;
%         endTime = 5;
%         numberOfSamples = endTime * 1/sampleTime + 1;
%         timeVector = (0:numberOfSamples) * sampleTime;
%         c= timeseries(timeVector*0,timeVector);
            model = 'Supine_SpeedGoat_v2_ES';
            open_system(model);
%         waveform = c;
%         set_param(model,'ExternalInput','waveform');
%         set_param(model,'LoadExternalInput','on');
%         set_param(model,'StopTime','Inf');
            evalc('slbuild(model)');

        end

        % Callback function
        function LoadinputsignalfileButtonPushed(app, event)
                        

             [baseFileName, folder] = uigetfile('*.mat');
             fullFileName = fullfile(folder, baseFileName);

             setappdata(0,'Input_File_address',fullFileName);
             disp("Selected stimulus file is : ")
             disp(fullFileName)

        end

        % Button pushed function: opensignalgeneratorButton
        function opensignalgeneratorButtonPushed(app, event)
            % To DO : Function for opening the signal generator and add a
            % signal to your application
            % Modify the name of the fonction 
            %Determine the output for signal generator
            % migration tool from GUIDE to app designer
            % I Add a output for the Ali_exp_gui but need to do with with
            % app designer(waiting the gui migration tool)
            addpath('C:\desktop\EMG_TQ_Modelling\exp_gui\signal_generator\');

            Ali_exp_gui();
        end

        % Button pushed function: ApplyAcquisitionParametersButton, 
        % ...and 1 other component
        function ApplyAcquisitionParametersButtonPushed(app, event)
             app.SelectAngleradDropDown.Value;
 
             head_initial_value = 0;
             head_final_value = str2double(app.SelectAngleradDropDown.Value);
             head_start_time = 0.5;
             head_finishtime = app.HoldTimesEditField.Value;
             head_slope = 0.05;
             tail_initial_value = str2double(app.SelectAngleradDropDown.Value);
             tail_final_value = 0;
             tail_start_time = 0;
             tail_finishtime = 10;
             tail_slope = 0.05;
             if head_initial_value>head_final_value
                 if head_slope>0
                   head_slope=head_slope.*-1;
                 end
             end
 
             if tail_initial_value>tail_final_value
                 if tail_slope>0
                   tail_slope=tail_slope.*-1;
                 end
             end
             
             a = gen_ramp_and_hold(head_initial_value,head_final_value,head_slope,head_start_time,head_finishtime);
             b = gen_ramp_and_hold(tail_initial_value,tail_final_value,tail_slope,tail_start_time,tail_finishtime);
             Temp_command(1,:)=[a(1,:) (a(1,end)+b(1,2:end))];
             Temp_command(2,:)=[a(2,:) b(2,2:end)];
             app.Command= timeseries(Temp_command(2,:) ,Temp_command(1,:));
 
             % Set the passive torque and MVC on the built simulink real-time model
             % The reason we have (1,1) for both is that in the original design, we maintained an array of Passive and MVC TQs at all anke positions
             % TODO: This must be changes to a scalar from array.
             posIndx = strcmp(app.SelectAngleradDropDown.Items,app.SelectAngleradDropDown.Value);
             passive_tq_value = app.PassiveTorque(posIndx);
             mvc_tq_value = app.AcquisitionMVC(posIndx);
             setparam(app.Target_PC,'Supine_SpeedGoat_v2_ES/Visual Feedback Controller/Passive_TQ','Value',passive_tq_value);
             setparam(app.Target_PC,'Supine_SpeedGoat_v2_ES/Visual Feedback Controller/MVC_TQ','Value',mvc_tq_value);
            
            % Adding a new row 

            % TODO: do we still need the gauges?
%             app.PassiveTorqueSavedNmGauge.Value = passive_tq_value;
%             app.MVCValueSavedNmGauge.Value = mvc_tq_value;
% 
%             app.PassiveTorqueSavedNumeric.Value = passive_tq_value;
%             app.MVCTorqueSavedNumeric.Value = mvc_tq_value;
          
        end

        % Button pushed function: StartCommandButton, StartcommandButton
        function StartcommandButtonPushed(app, event)
         % to do : problem with the lunch for the stimulation since I add
         % the recording 
         stop(app.DAQSession);
         status = getStatus(app.Target_PC.Stimulation,'all');
          if strcmp(status.In1,'RUNNING') && app.Command_sent == false
                app.StartCommandButton.Enable = 'off';
                app.StopButton_2.Enable = 'on';
                stop(app.Target_PC.Stimulation,1);
                reloadData(app.Target_PC.Stimulation,1,app.Command);
                start(app.Target_PC.Stimulation,1);
                app.Command_sent = true;
          else
              uialert(app.UIfigure,"Use the go Back to neutral position Button before start a new command");
          end


        end

        % Button pushed function: StopCommandButton, StopcommandButton
        function StopcommandButtonPushed(app, event)

             app.StartCommandButton.Enable = 'on';
             app.StopButton_2.Enable = 'off';
             app.GobackatneutralpositionButton.Enable ="on";
             stop(app.Target_PC.Stimulation,1);
                    
        end

        % Drop down opening function: SelectAngleradDropDown, 
        % ...and 1 other component
        function SelectangleRadDropDownOpening(app, event)
   % To do : Add some logic to obtain a default value 
            if isempty(app.AcquisitionAngle)
                uialert(app.UIFigure, 'Please press Initialize Session first.', 'Not Initialized');
            end


        end

        % Value changed function: SelectAngleradDropDown, 
        % ...and 1 other component
        function SelectangleRadDropDownValueChanged(app, event)


        end

        % Button pushed function: RecordButton, RecordButton_2
        function RecordButtonPushed(app, event)

          delete(app.DAQSession);
            app.DAQSession = [];
            
            s = daq.createSession('dt');
            s.Rate = app.SAMPLING_FREQUENCY;
            s.IsContinuous = true;


            ch0 = addAnalogInputChannel(s,"DT9857E-SYNC","0","Voltage"); % pos
            ch1 = addAnalogInputChannel(s,"DT9857E-SYNC","1","Voltage"); % Torque
            ch5 = addAnalogInputChannel(s,"DT9857E-SYNC","5","Voltage"); %Left TA
            ch6 = addAnalogInputChannel(s,"DT9857E-SYNC","6","Voltage"); %Left MG
            ch7 = addAnalogInputChannel(s,"DT9857E-SYNC","7","Voltage"); % left Soleus
            ch23 = addAnalogInputChannel(s,"DT9857E-SYNC","23","Voltage"); % left LG


            ch0.Range = [-10 10];
            ch1.Range = [-10 10];
            ch5.Range = [-10 10];
            ch6.Range = [-10 10];
            ch7.Range = [-10 10];
            ch23.Range = [-10 10];

            app.DAQSession = s;


            if isempty(app.subjectName) || isempty(app.trialLength)
                errordlg('You have not defined the subject name and trial length.','Invalid Subject Name or Trial Length','modal'); % uialert function
            else
                if ~isempty(app.DAQSession) && ~(app.DAQSession.IsRunning)
        
                    % Delete existing listener for DataAvailable event
                    delete(app.DataAvailableListener);
                    app.DataAvailableListener = [];
                    
                    % Create listener for DataAvailable event and specify callback function
                    app.DataAvailableListener = addlistener(app.DAQSession,'DataAvailable', ...
                        @(src,event) dataAvailable_Callback(app, src, event));
    
                    app.trialNumber = app.trialNumber + 1;
                    app.TrialNumberTextArea.Value = num2str(app.trialNumber);

                    if isempty(app.plotTitle) || strcmp(app.plotTitle,'desired signal')
                        app.plotTitle = 'Position';
                        app.signalIndex = find(strcmp(app.chanNames,app.plotTitle));
                    end

                    if isempty(app.plotTitle2) || strcmp(app.plotTitle2,'desired signal')
                        app.plotTitle2 = 'Torque';
                        app.signalIndex2 = find(strcmp(app.chanNames,app.plotTitle2));
                    end
                    if isempty(app.plotTitle3) || strcmp(app.plotTitle3,'desired signal')
                        app.plotTitle3 = 'TA EMG';
                        app.signalIndex3 = find(strcmp(app.chanNames,app.plotTitle3));
                    end
                    if isempty(app.plotTitle4) || strcmp(app.plotTitle4,'desired signal')
                        app.plotTitle4 = 'Sol EMG';
                        app.signalIndex4 = find(strcmp(app.chanNames,app.plotTitle4));
                    end
                    if isempty(app.plotTitle5) || strcmp(app.plotTitle5,'desired signal')
                        app.plotTitle5 = 'MG EMG';
                        app.signalIndex5 = find(strcmp(app.chanNames,app.plotTitle5));
                    end
                    if isempty(app.plotTitle6) || strcmp(app.plotTitle6,'desired signal')
                        app.plotTitle6 = 'LG EMG';
                        app.signalIndex6 = find(strcmp(app.chanNames,app.plotTitle6));
                    end

        
                    app.RecordButton_2.Enable = 'off';
                    app.StopButton_2.Enable = 'on';
                    app.LiveFeedbackDropDown.Enable = 'off';
                    app.LiveFeedbackDropDown_2.Enable = 'off';
                    app.LiveFeedbackDropDown_3.Enable = 'off';
                    app.LiveFeedbackDropDown_4.Enable = 'off';
                    app.LiveFeedbackDropDown_5.Enable = 'off';
                    app.LiveFeedbackDropDown_6.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                
                    % Create and open temporary binary file to log data to disk
                    app.TempFilename = tempname;
                    app.TempFile = fopen(app.TempFilename, 'w');
        
                    % Reset FIFO buffer data
                    app.DataFIFOBuffer = [];
                    app.DataFIFOBuffer2 = [];
                    app.DataFIFOBuffer3 = [];
                    app.DataFIFOBuffer4 = [];
                    app.DataFIFOBuffer5 = [];
                    app.DataFIFOBuffer6 = [];
                    app.TimestampsFIFOBuffer = [];
        
                    startBackground(app.DAQSession);
                    app.LogStatusTextArea.Value = 'Recording data started';
    
                end
            end

        end

        % Value changed function: SubjectNameEditField, 
        % ...and 1 other component
        function SubjectNameEditFieldValueChanged(app, event)
            value = app.SubjectNameEditField_2.Value;
            app.subjectName = replace(value, ' ', '_');
            app.dataFileName = [app.subjectName,'_',datestr(now,'ddmmyy')];
            
        end

        % Value changed function: TrialLengthsEditField, 
        % ...and 1 other component
        function TrialLengthsEditFieldValueChanged(app, event)
            app.trialLength = app.TrialLengthsEditField_2.Value;
        end

        % Button pushed function: StopButton, StopButton_2
        function StopButtonPushed(app, event)
             if ~isempty(app.DAQSession)
                stop(app.DAQSession);
            end
            
            app.RecordButton_2.Enable = 'on';
            app.StopButton_2.Enable = 'off';
            app.LiveFeedbackDropDown.Enable = 'on';
            app.LiveFeedbackDropDown_2.Enable = 'on';
            app.LiveFeedbackDropDown_3.Enable = 'on';
            app.LiveFeedbackDropDown_4.Enable = 'on';
            app.LiveFeedbackDropDown_5.Enable = 'on';
            app.LiveFeedbackDropDown_6.Enable = 'on';
            app.SubjectNameEditField.Enable = 'on';
            app.LogStatusTextArea.Value = 'Recording data stopped.'; 

            % close temporary binary file
            fclose(app.TempFile);
            
            app.Filename = app.dataFileName;
            % convert data from binary file to mat file
            matFilepath = fullfile(app.Filepath,app.Filename);
            app.LogStatusTextArea.Value = 'Saving data in progress';
            drawnow;
            
            binFile2MAT2FLB(app, app.TempFilename, matFilepath);
           % app.trialLength = 0;
            app.LogStatusTextArea.Value = sprintf('Saving data from trial ''%d'' to ''%s'' file has completed.', app.trialNumber,app.Filename);

            % if MVCPassiveTorque trial, we display the relevant plot
            if app.isMVCPassiveTorqueTrial
                plotTorque(app, matFilepath);
            end
        end

        % Value changed function: CommentEditField, CommentEditField_2
        function CommentEditFieldValueChanged(app, event)
                    app.comment = app.CommentEditField_2.Value;
        end

        % Value changed function: SelectaSignaltoshowDropDown
        function SelectaSignaltoshowDropDownValueChanged(app, event)
           value = app.SelectaSignaltoshowDropDown.Value;
            app.plotTitle = value;
            app.signalIndex = find(strcmp(app.chanNames,value));
        end

        % Value changed function: SelectaSignaltoshowDropDown_2
        function SelectaSignaltoshowDropDown_2ValueChanged(app, event)
          value = app.SelectaSignaltoshowDropDown_2.Value;
            app.plotTitle2 = value;
            app.signalIndex2 = find(strcmp(app.chanNames,value));
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
              isAcquiring = ~isempty(app.DAQSession) && app.DAQSession.IsRunning;
            if isAcquiring 
                question = 'Abort acquistion and close app?';

            else
                % Acquisition is stopped
                question = 'Close app?';
            end

            uiconfirm(app.UIFigure,question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,isAcquiring));
        end

        % Callback function
        function TrialNumberTextAreaValueChanged(app, event)

        end

        % Button pushed function: GobackatneutralpositionButton, 
        % ...and 1 other component
        function GobackatneutralpositionButtonPushed(app, event)

    status= getStatus(app.Target_PC.Stimulation,'all');
    if strcmp(status.In1,'RUNNING')

        stop(app.Target_PC.Stimulation,1);
    
    end
    initial_value = str2double(inputdlg('What is the current voltage on the display ?','Current position needed'))*0.1;
    final_value = 0;
    start_time = 1;
    finishtime = 10;
    slope = 0.05;

    if initial_value>final_value
        if slope>0
          slope=slope.*-1;
        end
    end
    a = gen_ramp_and_hold(initial_value,final_value,slope,start_time,finishtime);

    u = timeseries(a(2,:),a(1,:));
    
    reloadData(app.Target_PC.Stimulation,1,u);
    start(app.Target_PC.Stimulation,1);
    app.Command_sent = false;
    app.StartcommandButton.Enable = 'on';
    uialert(app.UIFigure,"Wait for the pedal to return to neutral before sending a command.");

        end

        % Value changed function: MaxDorsiflexionradEditField, 
        % ...and 1 other component
        function MaximumDorsiflexionRadEditFieldValueChanged(app, event)
            app.MaxDF = app.MaxDorsiflexionradEditField.Value;
            
        end

        % Value changed function: MaxPlantarflexionradEditField, 
        % ...and 1 other component
        function MaximumPlantarflexionRadEditFieldValueChanged(app, event)
            app.MaxPF = app.MaxPlantarflexionradEditField.Value;
            
        end

        % Value changed function: MVCValuetoEnterNmEditField
        function MVCValuetoEnterNmEditFieldValueChanged(app, event)
            value = app.MVCValueNmEditField.Value;

            Test_Cmp =strcmp(app.SelectAngleradDropDown.Value,app.SelectAngleradDropDown.Items);
            app.AcquisitionMVC(find(Test_Cmp))=value;

        end

        % Value changed function: HowmanypositiondoyouneedEditField, 
        % ...and 1 other component
        function HowmanypositiondoyouneedEditFieldValueChanged(app, event)
            app.N_Angles = app.ofPositionsEditField.Value;
            
        end

        % Value changed function: HoldTimesEditField, 
        % ...and 1 other component
        function HowmuchtimeforholdtheangleEditFieldValueChanged(app, event)
            app.Hold_Position_Time = app.HoldTimesEditField.Value;
            
        end

        % Value changed function: SelectaSignaltoshowDropDown_3
        function SelectaSignaltoshowDropDown_3ValueChanged(app, event)
            value = app.SelectaSignaltoshowDropDown_3.Value;
            app.plotTitle3 = value;
            app.signalIndex3 = find(strcmp(app.chanNames,value));
            
        end

        % Value changed function: SelectaSignaltoshowDropDown_4
        function SelectaSignaltoshowDropDown_4ValueChanged(app, event)
            value = app.SelectaSignaltoshowDropDown_4.Value;
            app.plotTitle4 = value;
            app.signalIndex4 = find(strcmp(app.chanNames,value));
        end

        % Callback function
        function pos(app, event)
            value = app.ofPositionsEditField.Value;
            
        end

        % Callback function
        function hold(app, event)
            value = app.HoldTimesEditField.Value;
            
        end

        % Callback function
        function tor(app, event)
            value = app.PassiveTorqueNmEditField.Value;
            
        end

        % Value changed function: LiveFeedbackDropDown
        function LiveFeedbackDropDownValueChanged(app, event)
            value = app.LiveFeedbackDropDown.Value;
            app.plotTitle = value;
            app.signalIndex = find(strcmp(app.chanNames,value));


        end

        % Value changed function: LiveFeedbackDropDown_2
        function LiveFeedbackDropDown_2ValueChanged(app, event)
            value = app.LiveFeedbackDropDown_2.Value;
            app.plotTitle2 = value;
            app.signalIndex2 = find(strcmp(app.chanNames,value));
        end

        % Value changed function: LiveFeedbackDropDown_3
        function LiveFeedbackDropDown_3ValueChanged(app, event)
            value = app.LiveFeedbackDropDown_3.Value;
            app.plotTitle3 = value;
            app.signalIndex3 = find(strcmp(app.chanNames,value));
        end

        % Value changed function: LiveFeedbackDropDown_4
        function LiveFeedbackDropDown_4ValueChanged(app, event)
            value = app.LiveFeedbackDropDown_4.Value;
            app.plotTitle4 = value;
            app.signalIndex4 = find(strcmp(app.chanNames,value));
        end

        % Value changed function: LiveFeedbackDropDown_5
        function LiveFeedbackDropDown_5ValueChanged(app, event)
            value = app.LiveFeedbackDropDown_5.Value;
            app.plotTitle5 = value;
            app.signalIndex5 = find(strcmp(app.chanNames,value));
        end

        % Value changed function: LiveFeedbackDropDown_6
        function LiveFeedbackDropDown_6ValueChanged(app, event)
            value = app.LiveFeedbackDropDown_6.Value;
            app.plotTitle6 = value;
            app.signalIndex6 = find(strcmp(app.chanNames,value));
            
        end

        % Button pushed function: InitializeSessionButton
        function InitializeSessionButtonPushed(app, event)
            if isempty(app.MaxDF) || isempty(app.MaxPF) || isempty(app.N_Angles)
                uialert(app.UIFigure, 'Please enter Max Dorsiflexion, Max Plantarflexion and # of Positions first.', 'Missing Parameters');
                return;
            end
            if app.N_Angles < 2
                uialert(app.UIFigure, 'Enter a number of angles greater than one', 'Invalid limit angle');
                return;
            end
            % generate angles and populate measurables array, moved from SelectangleRadDropDownOpening
            app.ROM = [app.MaxDF,app.MaxPF];
            angles = linspace(app.MaxDF,app.MaxPF,app.N_Angles);
            app.AcquisitionAngle=angles;
            app.AcquisitionMVC = zeros(1,app.N_Angles);
            app.PassiveTorque= zeros(1,app.N_Angles);
            
            % populate select angle dropdown, moved from SelectangleRadDropDownOpening
            for i = 1:length(angles)
                app.SelectAngleradDropDown.Items(i) = {num2str(angles(i))};
            end

            % populate table
            app.UITable.Data = [num2cell(angles'), num2cell(zeros(app.N_Angles, 1)), num2cell(zeros(app.N_Angles, 1))];
        end

        % Value changed function: MVCPassiveTorqueToggle
        function MVCPassiveTorqueToggleValueChanged(app, event)
            app.isMVCPassiveTorqueTrial = app.MVCPassiveTorqueToggle.Value == "On";

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            rowIdx = indices(1);
            colIdx = indices(2);
            switch colIdx
                case 2
                    app.PassiveTorque(rowIdx) = newData;
                case 3
                    app.AcquisitionMVC(rowIdx) = newData;
            end
        end

        % Button pushed function: SaveRangeofMotiontoFileButton
        function SaveRangeofMotiontoFileButtonPushed(app, event)
            if isempty(app.UITable.Data)
                uialert(app.UIFigure, 'No metadata to save, "Initialize Session" first. ', 'Missing Parameters');
                return;
            end
            if isempty(app.SubjectNameEditField_2.Value)
                uialert(app.UIFigure, 'Please enter Subject name first.', 'Missing Parameters');
                return;
            end
            tableData = cell2mat(app.UITable.Data);

            angle = tableData(:,1);
            torque = tableData(:,2);
            mvc_tq = tableData(:,3);

            trial_metadata.angle = angle;
            trial_metadata.torque = torque;
            trial_metadata.mvc_tq = mvc_tq;

            savePath = fullfile(app.Filepath, [app.dataFileName,'_metadata']);
            save(savePath, 'trial_metadata')
            app.LogStatusTextArea.Value = sprintf('Saving metadata for subject ''%s'' to ''%s'' file has completed.', app.SubjectNameEditField_2.Value, savePath);
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            [fileName, filePath] = uigetfile('*.mat', 'Select Metadata File', app.Filepath);

            if isequal(fileName, 0)
                return;
            end
            loaded = load(fullfile(filePath, fileName));
            % struct is inside trial_metadata field
            if ~isfield(loaded, 'trial_metadata') 
                uialert(app.UIFigure, 'Selected file does not contain valid metadata.', 'Invalid File');
                return;
            end

            % re-init variables
            app.PassiveTorque = loaded.trial_metadata.torque';
            app.AcquisitionMVC = loaded.trial_metadata.mvc_tq';
            app.AcquisitionAngle = loaded.trial_metadata.angle';
            
            % re-populate table
            app.UITable.Data = [num2cell(loaded.trial_metadata.angle), ...
                                num2cell(loaded.trial_metadata.torque), ...
                                num2cell(loaded.trial_metadata.mvc_tq)];
            angles = loaded.trial_metadata.angle;
            for i = 1:length(angles)
                app.SelectAngleradDropDown.Items(i) = {num2str(angles(i))};
            end
            
            app.LogStatusTextArea.Value = ['Loaded metadata from: ', fileName];
        end

        % Value changed function: MaxDorsiflexionradEditField_2
        function MaxDorsiflexionradEditField_2ValueChanged(app, event)
            value = app.MaxDorsiflexionradEditField_2.Value;

            app.MaxDF = app.MaxDorsiflexionradEditField.Value;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.HardwareinitialisationTab = uitab(app.TabGroup);
            app.HardwareinitialisationTab.Title = 'Hardware initialisation';
            app.GridLayout2 = uigridlayout(app.HardwareinitialisationTab);
            app.ConnectButton = slrealtime.ui.control.ConnectButton(app.GridLayout2);
            app.StartStopButton = slrealtime.ui.control.StartStopButton(app.GridLayout2);
            app.TargetSelector = slrealtime.ui.control.TargetSelector(app.GridLayout2);
            app.LoadButton = slrealtime.ui.control.LoadButton(app.GridLayout2);
            app.SelectPreferenceFileButton = uibutton(app.GridLayout2, 'push');
            app.SelectPreferenceFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPreferenceFileButtonPushed, true);
            app.SelectPreferenceFileButton.Text = 'Select Preference File';
            app.LogStatusPanel = uipanel(app.GridLayout2);
            app.LogStatusPanel.Title = 'Log Status';
            app.GridLayout10 = uigridlayout(app.LogStatusPanel);
            app.LogStatusTextArea = uitextarea(app.GridLayout10);
            app.InputSignalTab = uitab(app.TabGroup);
            app.InputSignalTab.Title = 'Input Signal';
            app.GridLayout3 = uigridlayout(app.InputSignalTab);
            app.PassiveTorquetoEnterNmSpinnerLabel = uilabel(app.GridLayout3);
            app.PassiveTorquetoEnterNmSpinnerLabel.Text = 'Passive Torque to Enter (Nm)';
            app.PassiveTorquetoEnterNmSpinner = uispinner(app.GridLayout3);
            app.PassiveTorquetoEnterNmSpinner.ValueChangedFcn = createCallbackFcn(app, @PassiveTorquetoEnterNmSpinnerValueChanged, true);
            app.SelectangleRadDropDownLabel = uilabel(app.GridLayout3);
            app.SelectangleRadDropDownLabel.Text = 'Select angle (Rad)';
            app.SelectangleRadDropDown = uidropdown(app.GridLayout3);
            app.SelectangleRadDropDown.Items = {'Enter the limit before'};
            app.SelectangleRadDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectangleRadDropDownOpening, true);
            app.SelectangleRadDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectangleRadDropDownValueChanged, true);
            app.SelectangleRadDropDown.Value = 'Enter the limit before';
            app.ApplyAcquisitionParametersButton = uibutton(app.GridLayout3, 'push');
            app.ApplyAcquisitionParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAcquisitionParametersButtonPushed, true);
            app.ApplyAcquisitionParametersButton.Text = 'Apply Acquisition Parameters';
            app.StartcommandButton = uibutton(app.GridLayout3, 'push');
            app.StartcommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartcommandButtonPushed, true);
            app.StartcommandButton.Text = 'Start command';
            app.StopcommandButton = uibutton(app.GridLayout3, 'push');
            app.StopcommandButton.ButtonPushedFcn = createCallbackFcn(app, @StopcommandButtonPushed, true);
            app.StopcommandButton.Text = 'Stop command';
            app.GobackatneutralpositionButton = uibutton(app.GridLayout3, 'push');
            app.GobackatneutralpositionButton.ButtonPushedFcn = createCallbackFcn(app, @GobackatneutralpositionButtonPushed, true);
            app.GobackatneutralpositionButton.Text = 'Go back at neutral position';
            app.MaximumDorsiflexionRadEditFieldLabel = uilabel(app.GridLayout3);
            app.MaximumDorsiflexionRadEditFieldLabel.Text = 'Maximum Dorsiflexion (Rad)';
            app.MaximumDorsiflexionRadEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaximumDorsiflexionRadEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumDorsiflexionRadEditFieldValueChanged, true);
            app.MaximumPlantarflexionRadLabel = uilabel(app.GridLayout3);
            app.MaximumPlantarflexionRadLabel.Text = 'Maximum Plantarflexion (Rad)';
            app.MaximumPlantarflexionRadEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaximumPlantarflexionRadEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPlantarflexionRadEditFieldValueChanged, true);
            app.MVCValuetoEnterNmEditFieldLabel = uilabel(app.GridLayout3);
            app.MVCValuetoEnterNmEditFieldLabel.Text = 'MVC Value to Enter (Nm)';
            app.MVCValuetoEnterNmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MVCValuetoEnterNmEditField.ValueChangedFcn = createCallbackFcn(app, @MVCValuetoEnterNmEditFieldValueChanged, true);
            app.HowmanypositiondoyouneedEditFieldLabel = uilabel(app.GridLayout3);
            app.HowmanypositiondoyouneedEditFieldLabel.Text = 'How many position do you need ?';
            app.HowmanypositiondoyouneedEditField = uieditfield(app.GridLayout3, 'numeric');
            app.HowmanypositiondoyouneedEditField.ValueChangedFcn = createCallbackFcn(app, @HowmanypositiondoyouneedEditFieldValueChanged, true);
            app.HowmuchtimeforholdtheangleEditFieldLabel = uilabel(app.GridLayout3);
            app.HowmuchtimeforholdtheangleEditFieldLabel.Text = 'How much time for hold the angle ?';
            app.HowmuchtimeforholdtheangleEditField = uieditfield(app.GridLayout3, 'numeric');
            app.HowmuchtimeforholdtheangleEditField.ValueChangedFcn = createCallbackFcn(app, @HowmuchtimeforholdtheangleEditFieldValueChanged, true);
            app.MVCValueSavedNmGaugeLabel = uilabel(app.GridLayout3);
            app.MVCValueSavedNmGaugeLabel.Text = 'MVC Value Saved (Nm)';
            app.MVCValueSavedNmGauge = uigauge(app.GridLayout3, 'ninetydegree');
            app.MVCValueSavedNmGauge.Limits = [-100 0];
            app.PassiveTorqueSavedNmGaugeLabel = uilabel(app.GridLayout3);
            app.PassiveTorqueSavedNmGaugeLabel.Text = 'Passive Torque Saved (Nm)';
            app.PassiveTorqueSavedNmGauge = uigauge(app.GridLayout3, 'ninetydegree');
            app.PassiveTorqueSavedNmGauge.Limits = [-15 5];
            app.MVCTorqueSavedNumeric = uieditfield(app.GridLayout3, 'numeric');
            app.PassiveTorqueSavedNumeric = uieditfield(app.GridLayout3, 'numeric');
            app.DataAcquisitionTab = uitab(app.TabGroup);
            app.DataAcquisitionTab.Title = 'Data Acquisition';
            app.GridLayout4 = uigridlayout(app.DataAcquisitionTab);
            app.RecordButton = uibutton(app.GridLayout4, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SubjectNameEditFieldLabel = uilabel(app.GridLayout4);
            app.SubjectNameEditFieldLabel.Text = 'Subject Name';
            app.SubjectNameEditField = uieditfield(app.GridLayout4, 'text');
            app.SubjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectNameEditFieldValueChanged, true);
            app.TrialLengthsEditFieldLabel = uilabel(app.GridLayout4);
            app.TrialLengthsEditFieldLabel.Text = 'Trial Length [s]';
            app.TrialLengthsEditField = uieditfield(app.GridLayout4, 'text');
            app.TrialLengthsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialLengthsEditFieldValueChanged, true);
            app.TrialLengthsEditField.Value = '0';
            app.StopButton = uibutton(app.GridLayout4, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop ';
            app.TrialNumberEditFieldLabel = uilabel(app.GridLayout4);
            app.TrialNumberEditFieldLabel.Text = 'Trial Number';
            app.TrialNumberTextArea = uieditfield(app.GridLayout4, 'text');
            app.TrialNumberTextArea.Value = '0';
            app.CommentEditFieldLabel = uilabel(app.GridLayout4);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.GridLayout4, 'text');
            app.CommentEditField.ValueChangedFcn = createCallbackFcn(app, @CommentEditFieldValueChanged, true);
            app.SelectaSignaltoshowDropDown = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown.Items = {'Position', 'Torque', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDownValueChanged, true);
            app.SelectaSignaltoshowDropDown.Value = 'Position';
            app.SelectaSignaltoshowDropDown_2 = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown_2.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDown_2ValueChanged, true);
            app.SelectaSignaltoshowDropDown_2.Value = 'Position';
            app.SelectaSignaltoshowDropDown_3 = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown_3.Items = {'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDown_3ValueChanged, true);
            app.SelectaSignaltoshowDropDown_3.Value = 'TA EMG';
            app.SelectaSignaltoshowDropDown_4 = uidropdown(app.GridLayout4);
            app.SelectaSignaltoshowDropDown_4.Items = {'Sol EMG', 'TA EMG', 'MG EMG', 'LG EMG', 'Option 1'};
            app.SelectaSignaltoshowDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SelectaSignaltoshowDropDown_4ValueChanged, true);
            app.SelectaSignaltoshowDropDown_4.Value = 'Sol EMG';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.GridLayout5 = uigridlayout(app.Tab2);
            app.ControlPanel = uipanel(app.GridLayout5);
            app.ControlPanel.Title = 'Control';
            app.GridLayout6 = uigridlayout(app.ControlPanel);
            app.AnklePsotionandCommandPanel = uipanel(app.GridLayout6);
            app.AnklePsotionandCommandPanel.Title = 'Ankle Psotion and Command';
            app.GridLayout9 = uigridlayout(app.AnklePsotionandCommandPanel);
            app.MaxDorsiflexionradEditFieldLabel = uilabel(app.GridLayout9);
            app.MaxDorsiflexionradEditFieldLabel.Text = 'Max Dorsiflexion (rad)';
            app.MaxDorsiflexionradEditField = uieditfield(app.GridLayout9, 'numeric');
            app.MaxDorsiflexionradEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumDorsiflexionRadEditFieldValueChanged, true);
            app.MaxPlantarflexionradEditFieldLabel = uilabel(app.GridLayout9);
            app.MaxPlantarflexionradEditFieldLabel.Text = 'Max Plantarflexion (rad)';
            app.MaxPlantarflexionradEditField = uieditfield(app.GridLayout9, 'numeric');
            app.MaxPlantarflexionradEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPlantarflexionRadEditFieldValueChanged, true);
            app.ofPositionsEditFieldLabel = uilabel(app.GridLayout9);
            app.ofPositionsEditFieldLabel.Text = '# of Positions';
            app.ofPositionsEditField = uieditfield(app.GridLayout9, 'numeric');
            app.ofPositionsEditField.ValueChangedFcn = createCallbackFcn(app, @HowmanypositiondoyouneedEditFieldValueChanged, true);
            app.HoldTimesEditFieldLabel = uilabel(app.GridLayout9);
            app.HoldTimesEditFieldLabel.Text = 'Hold Time (s)';
            app.HoldTimesEditField = uieditfield(app.GridLayout9, 'numeric');
            app.HoldTimesEditField.ValueChangedFcn = createCallbackFcn(app, @HowmuchtimeforholdtheangleEditFieldValueChanged, true);
            app.ApplyParametersButton = uibutton(app.GridLayout9, 'push');
            app.ApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyAcquisitionParametersButtonPushed, true);
            app.ApplyParametersButton.Text = '🛠️ Apply Parameters';
            app.StartCommandButton = uibutton(app.GridLayout9, 'push');
            app.StartCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StartcommandButtonPushed, true);
            app.StartCommandButton.Text = '►Start Command';
            app.StopCommandButton = uibutton(app.GridLayout9, 'push');
            app.StopCommandButton.ButtonPushedFcn = createCallbackFcn(app, @StopcommandButtonPushed, true);
            app.StopCommandButton.Text = '◼ Stop Command';
            app.SaveRangeofMotiontoFileButton = uibutton(app.GridLayout9, 'push');
            app.SaveRangeofMotiontoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRangeofMotiontoFileButtonPushed, true);
            app.SaveRangeofMotiontoFileButton.Text = '💾Save Range of Motion to File';
            app.GotoNeutralButton = uibutton(app.GridLayout9, 'push');
            app.GotoNeutralButton.ButtonPushedFcn = createCallbackFcn(app, @GobackatneutralpositionButtonPushed, true);
            app.GotoNeutralButton.Text = '↩ Go to Neutral';
            app.SelectAngleradDropDownLabel = uilabel(app.GridLayout9);
            app.SelectAngleradDropDownLabel.Text = 'Select Angle (rad)';
            app.SelectAngleradDropDown = uidropdown(app.GridLayout9);
            app.SelectAngleradDropDown.Items = {};
            app.SelectAngleradDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectangleRadDropDownOpening, true);
            app.SelectAngleradDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectangleRadDropDownValueChanged, true);
            app.SelectAngleradDropDown.Value = {};
            app.InitializeSessionButton = uibutton(app.GridLayout9, 'push');
            app.InitializeSessionButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeSessionButtonPushed, true);
            app.InitializeSessionButton.Text = 'Initialize Session';
            app.LoadButton_3 = uibutton(app.GridLayout9, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.TrialRecordingPanel = uipanel(app.GridLayout6);
            app.TrialRecordingPanel.Title = 'Trial Recording';
            app.GridLayout11 = uigridlayout(app.TrialRecordingPanel);
            app.TrialLengthsEditField_2Label = uilabel(app.GridLayout11);
            app.TrialLengthsEditField_2Label.Text = 'Trial Length (s)';
            app.TrialLengthsEditField_2 = uieditfield(app.GridLayout11, 'numeric');
            app.TrialLengthsEditField_2.ValueChangedFcn = createCallbackFcn(app, @TrialLengthsEditFieldValueChanged, true);
            app.TrialEditFieldLabel = uilabel(app.GridLayout11);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialEditField = uieditfield(app.GridLayout11, 'numeric');
            app.CommentEditField_2Label = uilabel(app.GridLayout11);
            app.CommentEditField_2Label.Text = 'Comment';
            app.CommentEditField_2 = uieditfield(app.GridLayout11, 'text');
            app.CommentEditField_2.ValueChangedFcn = createCallbackFcn(app, @CommentEditFieldValueChanged, true);
            app.SubjectNameEditField_2Label = uilabel(app.GridLayout11);
            app.SubjectNameEditField_2Label.Text = 'Subject Name';
            app.SubjectNameEditField_2 = uieditfield(app.GridLayout11, 'text');
            app.SubjectNameEditField_2.ValueChangedFcn = createCallbackFcn(app, @SubjectNameEditFieldValueChanged, true);
            app.RecordButton_2 = uibutton(app.GridLayout11, 'push');
            app.RecordButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton_2.Text = '● Record';
            app.StopButton_2 = uibutton(app.GridLayout11, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton_2.Text = '◼ Stop';
            app.MVCPassiveTorqueToggle = uiswitch(app.GridLayout11, 'slider');
            app.MVCPassiveTorqueToggle.ValueChangedFcn = createCallbackFcn(app, @MVCPassiveTorqueToggleValueChanged, true);
            app.MVCPassiveTorquerecordingLabel = uilabel(app.GridLayout11);
            app.MVCPassiveTorquerecordingLabel.Text = 'MVC + Passive Torque recording';
            app.MonitoringPanel = uipanel(app.GridLayout5);
            app.MonitoringPanel.Title = 'Monitoring';
            app.GridLayout7 = uigridlayout(app.MonitoringPanel);
            app.AnklePositionLogPanel = uipanel(app.GridLayout7);
            app.AnklePositionLogPanel.Title = 'Ankle Position Log';
            app.GridLayout8 = uigridlayout(app.AnklePositionLogPanel);
            app.UITable = uitable(app.GridLayout8);
            app.UITable.ColumnName = {'Ankle Angle (Rad)'; 'Passive Torque (Nm)'; 'MVC Value (Nm)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.GridLayout13 = uigridlayout(app.GridLayout8);
            app.MVCPlot = uiaxes(app.GridLayout13);
            title(app.MVCPlot, 'MVC Plot')
            xlabel(app.MVCPlot, 'X')
            ylabel(app.MVCPlot, 'Y')
            zlabel(app.MVCPlot, 'Z')
            app.LiveFeedbackPanel = uipanel(app.GridLayout7);
            app.LiveFeedbackPanel.Title = 'Live Feedback';
            app.GridLayout12 = uigridlayout(app.LiveFeedbackPanel);
            app.UIAxes6 = uiaxes(app.GridLayout12);
            xlabel(app.UIAxes6, 'Time (s)')
            ylabel(app.UIAxes6, 'Volt (V)')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout12);
            xlabel(app.UIAxes5, 'Time (s)')
            ylabel(app.UIAxes5, 'Volt (V)')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout12);
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Volt (V)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout12);
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Volt (V)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout12);
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Volt (V)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout12);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Volt (V)')
            zlabel(app.UIAxes, 'Z')
            app.LiveFeedbackDropDown = uidropdown(app.GridLayout12);
            app.LiveFeedbackDropDown.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown.ValueChangedFcn = createCallbackFcn(app, @LiveFeedbackDropDownValueChanged, true);
            app.LiveFeedbackDropDown.Value = 'Position';
            app.LiveFeedbackDropDown_2 = uidropdown(app.GridLayout12);
            app.LiveFeedbackDropDown_2.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LiveFeedbackDropDown_2ValueChanged, true);
            app.LiveFeedbackDropDown_2.Value = 'Torque';
            app.LiveFeedbackDropDown_3 = uidropdown(app.GridLayout12);
            app.LiveFeedbackDropDown_3.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_3.ValueChangedFcn = createCallbackFcn(app, @LiveFeedbackDropDown_3ValueChanged, true);
            app.LiveFeedbackDropDown_3.Value = 'TA EMG';
            app.LiveFeedbackDropDown_4 = uidropdown(app.GridLayout12);
            app.LiveFeedbackDropDown_4.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_4.ValueChangedFcn = createCallbackFcn(app, @LiveFeedbackDropDown_4ValueChanged, true);
            app.LiveFeedbackDropDown_4.Value = 'Sol EMG';
            app.LiveFeedbackDropDown_5 = uidropdown(app.GridLayout12);
            app.LiveFeedbackDropDown_5.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_5.ValueChangedFcn = createCallbackFcn(app, @LiveFeedbackDropDown_5ValueChanged, true);
            app.LiveFeedbackDropDown_5.Value = 'MG EMG';
            app.LiveFeedbackDropDown_6 = uidropdown(app.GridLayout12);
            app.LiveFeedbackDropDown_6.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_6.ValueChangedFcn = createCallbackFcn(app, @LiveFeedbackDropDown_6ValueChanged, true);
            app.LiveFeedbackDropDown_6.Value = 'LG EMG';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.GridLayout5_2 = uigridlayout(app.Tab3);
            app.ControlPanel_2 = uipanel(app.GridLayout5_2);
            app.ControlPanel_2.Title = 'Control';
            app.GridLayout6_2 = uigridlayout(app.ControlPanel_2);
            app.AnklePostionandCommandPanel = uipanel(app.GridLayout6_2);
            app.AnklePostionandCommandPanel.Title = 'Ankle Postion and Command';
            app.GridLayout9_2 = uigridlayout(app.AnklePostionandCommandPanel);
            app.MaxDorsiflexionradEditField_2Label = uilabel(app.GridLayout9_2);
            app.MaxDorsiflexionradEditField_2Label.Text = 'Max Dorsiflexion (rad)';
            app.MaxDorsiflexionradEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.MaxDorsiflexionradEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxDorsiflexionradEditField_2ValueChanged, true);
            app.MaxPlantarflexionradEditField_2Label = uilabel(app.GridLayout9_2);
            app.MaxPlantarflexionradEditField_2Label.Text = 'Max Plantarflexion (rad)';
            app.MaxPlantarflexionradEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.ApplyParametersButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.ApplyParametersButton_2.Text = '🛠️ Apply Parameters';
            app.StartCommandButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.StartCommandButton_2.Text = '►Start Command';
            app.StopCommandButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.StopCommandButton_2.Text = '◼ Stop Command';
            app.GotoNeutralButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.GotoNeutralButton_2.Text = '↩ Go to Neutral';
            app.InitializeSessionButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.InitializeSessionButton_2.Text = 'Initialize Session';
            app.ofPositionsEditField_2Label = uilabel(app.GridLayout9_2);
            app.ofPositionsEditField_2Label.Text = '# of Positions';
            app.ofPositionsEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.HoldTimesEditField_2Label = uilabel(app.GridLayout9_2);
            app.HoldTimesEditField_2Label.Text = 'Hold Time (s)';
            app.HoldTimesEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
            app.SelectAngleradDropDown_2Label = uilabel(app.GridLayout9_2);
            app.SelectAngleradDropDown_2Label.Text = 'Select Angle (rad)';
            app.SelectAngleradDropDown_2 = uidropdown(app.GridLayout9_2);
            app.SelectAngleradDropDown_2.Items = {};
            app.SelectAngleradDropDown_2.Value = {};
            app.SaveRangeofMotiontoFileButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.SaveRangeofMotiontoFileButton_2.Text = '💾Save Range of Motion to File';
            app.LoadButton_2 = uibutton(app.GridLayout9_2, 'push');
            app.LoadButton_2.Text = 'Load';
            app.TrialRecordingPanel_2 = uipanel(app.GridLayout6_2);
            app.TrialRecordingPanel_2.Title = 'Trial Recording';
            app.GridLayout11_2 = uigridlayout(app.TrialRecordingPanel_2);
            app.TrialLengthsEditField_3Label = uilabel(app.GridLayout11_2);
            app.TrialLengthsEditField_3Label.Text = 'Trial Length (s)';
            app.TrialLengthsEditField_3 = uieditfield(app.GridLayout11_2, 'numeric');
            app.TrialEditField_2Label = uilabel(app.GridLayout11_2);
            app.TrialEditField_2Label.Text = 'Trial #';
            app.TrialEditField_2 = uieditfield(app.GridLayout11_2, 'numeric');
            app.CommentEditField_3Label = uilabel(app.GridLayout11_2);
            app.CommentEditField_3Label.Text = 'Comment';
            app.CommentEditField_3 = uieditfield(app.GridLayout11_2, 'text');
            app.SubjectNameEditField_3Label = uilabel(app.GridLayout11_2);
            app.SubjectNameEditField_3Label.Text = 'Subject Name';
            app.SubjectNameEditField_3 = uieditfield(app.GridLayout11_2, 'text');
            app.RecordButton_3 = uibutton(app.GridLayout11_2, 'push');
            app.RecordButton_3.Text = '● Record';
            app.StopButton_3 = uibutton(app.GridLayout11_2, 'push');
            app.StopButton_3.Text = '◼ Stop';
            app.opensignalgeneratorButton = uibutton(app.GridLayout11_2, 'push');
            app.opensignalgeneratorButton.ButtonPushedFcn = createCallbackFcn(app, @opensignalgeneratorButtonPushed, true);
            app.opensignalgeneratorButton.Text = 'ƒ open signal generator';
            app.SelectTrajectoryDropDownLabel = uilabel(app.GridLayout11_2);
            app.SelectTrajectoryDropDownLabel.Text = 'Select Trajectory';
            app.SelectTrajectoryDropDown = uidropdown(app.GridLayout11_2);
            app.MVCPassiveTorqueToggle_2 = uiswitch(app.GridLayout11_2, 'slider');
            app.MVCPassiveTorquerecordingLabel_2 = uilabel(app.GridLayout11_2);
            app.MVCPassiveTorquerecordingLabel_2.Text = 'MVC + Passive Torque recording';
            app.MonitoringPanel_2 = uipanel(app.GridLayout5_2);
            app.MonitoringPanel_2.Title = 'Monitoring';
            app.GridLayout7_2 = uigridlayout(app.MonitoringPanel_2);
            app.AnklePositionLogPanel_2 = uipanel(app.GridLayout7_2);
            app.AnklePositionLogPanel_2.Title = 'Ankle Position Log';
            app.GridLayout8_2 = uigridlayout(app.AnklePositionLogPanel_2);
            app.UITable_2 = uitable(app.GridLayout8_2);
            app.UITable_2.ColumnName = {'Ankle Angle (Rad)'; 'Passive Torque (Nm)'; 'MVC Value (Nm)'};
            app.UITable_2.RowName = {};
            app.GridLayout14 = uigridlayout(app.GridLayout8_2);
            app.UIAxes7 = uiaxes(app.GridLayout14);
            title(app.UIAxes7, 'MVC Plot')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.LiveFeedbackPanel_2 = uipanel(app.GridLayout7_2);
            app.LiveFeedbackPanel_2.Title = 'Live Feedback';
            app.GridLayout12_2 = uigridlayout(app.LiveFeedbackPanel_2);
            app.UIAxes6_2 = uiaxes(app.GridLayout12_2);
            xlabel(app.UIAxes6_2, 'Time (s)')
            ylabel(app.UIAxes6_2, 'Volt (V)')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes5_2 = uiaxes(app.GridLayout12_2);
            xlabel(app.UIAxes5_2, 'Time (s)')
            ylabel(app.UIAxes5_2, 'Volt (V)')
            zlabel(app.UIAxes5_2, 'Z')
            app.UIAxes4_2 = uiaxes(app.GridLayout12_2);
            xlabel(app.UIAxes4_2, 'Time (s)')
            ylabel(app.UIAxes4_2, 'Volt (V)')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.GridLayout12_2);
            xlabel(app.UIAxes3_2, 'Time (s)')
            ylabel(app.UIAxes3_2, 'Volt (V)')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.GridLayout12_2);
            xlabel(app.UIAxes2_2, 'Time (s)')
            ylabel(app.UIAxes2_2, 'Volt (V)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout12_2);
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Volt (V)')
            zlabel(app.UIAxes_2, 'Z')
            app.LiveFeedbackDropDown_7 = uidropdown(app.GridLayout12_2);
            app.LiveFeedbackDropDown_7.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_7.Value = 'Position';
            app.LiveFeedbackDropDown_8 = uidropdown(app.GridLayout12_2);
            app.LiveFeedbackDropDown_8.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_8.Value = 'Torque';
            app.LiveFeedbackDropDown_9 = uidropdown(app.GridLayout12_2);
            app.LiveFeedbackDropDown_9.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_9.Value = 'TA EMG';
            app.LiveFeedbackDropDown_10 = uidropdown(app.GridLayout12_2);
            app.LiveFeedbackDropDown_10.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_10.Value = 'Sol EMG';
            app.LiveFeedbackDropDown_11 = uidropdown(app.GridLayout12_2);
            app.LiveFeedbackDropDown_11.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_11.Value = 'MG EMG';
            app.LiveFeedbackDropDown_12 = uidropdown(app.GridLayout12_2);
            app.LiveFeedbackDropDown_12.Items = {'Torque', 'Position', 'TA EMG', 'MG EMG', 'LG EMG', 'Sol EMG'};
            app.LiveFeedbackDropDown_12.Value = 'LG EMG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Supine_GUI_seperate_tabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end